classdef ControlSistemaPortatil5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Aplicacion_GridLayout           matlab.ui.container.GridLayout
        Presentacion_Panel              matlab.ui.container.Panel
        CIO_Imagen                      matlab.ui.control.Image
        Desarrolladores_TextArea        matlab.ui.control.TextArea
        Titulo_Proyecto_TextArea        matlab.ui.control.TextArea
        Informacion_Sistema_Panel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Monitor_Serial_TextArea         matlab.ui.control.TextArea
        MonitorSerialLabel              matlab.ui.control.Label
        Puerto_DropDown                 matlab.ui.control.DropDown
        PuertoDropDownLabel             matlab.ui.control.Label
        Sistema_Operando_CheckBox       matlab.ui.control.CheckBox
        Conexion_Establecida_CheckBox   matlab.ui.control.CheckBox
        SALIR_Button                    matlab.ui.control.Button
        Desconectar_Button              matlab.ui.control.Button
        Conectar_Button                 matlab.ui.control.Button
        Controles_Manuales_Automticos_Panel  matlab.ui.container.Panel
        Ir_Inicio_Button                matlab.ui.control.Button
        Guardar_Inicio_Button           matlab.ui.control.Button
        Lectura_Sensor_EditField        matlab.ui.control.NumericEditField
        Lectura_Sensor_Slider           matlab.ui.control.Slider
        LecturadeSensorLabel            matlab.ui.control.Label
        SENSOR_0_grados_CheckBox        matlab.ui.control.CheckBox
        GIRO_MENOS_sensor_Button        matlab.ui.control.Button
        SENSOR_2theta_Label             matlab.ui.control.Label
        Angulo_Actual_sensor_EditField  matlab.ui.control.NumericEditField
        nguloActualEditFieldLabel_2     matlab.ui.control.Label
        GIRO_MAS_sensor_Button          matlab.ui.control.Button
        Ir_Home_Button                  matlab.ui.control.Button
        MUESTRA_0_grados_CheckBox       matlab.ui.control.CheckBox
        Valor_Laser_EditField           matlab.ui.control.NumericEditField
        Leer_Sensor_Button              matlab.ui.control.Button
        Intensidad_Laser_Slider         matlab.ui.control.Slider
        IntensidaddeLserSliderLabel     matlab.ui.control.Label
        Detener_Motores_Button          matlab.ui.control.Button
        Avance_Spinner                  matlab.ui.control.Spinner
        AvanceLabel                     matlab.ui.control.Label
        Angulo_Actual_Mustra_EditField  matlab.ui.control.NumericEditField
        nguloActualEditFieldLabel       matlab.ui.control.Label
        MUESTRA_theta_Label             matlab.ui.control.Label
        GIRO_MAS_muestra_Button         matlab.ui.control.Button
        GIRO_MENOS_muestra_Button       matlab.ui.control.Button
        Funcion_TabGroup                matlab.ui.container.TabGroup
        Descripcion_Tab                 matlab.ui.container.Tab
        Descripcion_GridLayout          matlab.ui.container.GridLayout
        EspecificacionesTcnicasTextArea  matlab.ui.control.TextArea
        EspecificacionesTcnicasTextAreaLabel  matlab.ui.control.Label
        ObjetivodelInstrumentoTextArea  matlab.ui.control.TextArea
        ObjetivodelInstrumentoTextAreaLabel  matlab.ui.control.Label
        SECIHTI_Imagen                  matlab.ui.control.Image
        SECIHTI_Imagen_2                matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Barrido_Angular_Tab             matlab.ui.container.Tab
        Barrido_Angular_GridLayout      matlab.ui.container.GridLayout
        Lectura_Sensor_Duplicado_Slider  matlab.ui.control.Slider
        LecturadeSensorLabel_2          matlab.ui.control.Label
        ParmetrosdeBarridongularPanel   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Resolucion_Barrido_DropDown     matlab.ui.control.DropDown
        ResolucinLabel                  matlab.ui.control.Label
        Angulo_Final_Barrido_Spinner    matlab.ui.control.Spinner
        AvanceLabel_2                   matlab.ui.control.Label
        Iniciar_Barrido_Button          matlab.ui.control.Button
        Grafica_Barrido_Angular_Axes    matlab.ui.control.UIAxes
        Angulo_Fijo_Tab                 matlab.ui.container.Tab
        Angulo_Fijo_GridLayout          matlab.ui.container.GridLayout
        Lectura_Sensor_Triplicado_Slider  matlab.ui.control.Slider
        LecturadeSensorLabel_3          matlab.ui.control.Label
        ParmetrosdenguloFijoPanel       matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Tiempo_Muestreo_DropDown        matlab.ui.control.DropDown
        ResolucinLabel_2                matlab.ui.control.Label
        Tiempo_Total_Spinner            matlab.ui.control.Spinner
        AvanceLabel_3                   matlab.ui.control.Label
        Iniciar_Medicion_Button         matlab.ui.control.Button
        Grafica_Angulo_Fijo_Axes        matlab.ui.control.UIAxes
        Angulo_Brewster_Tab             matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Resultados_Brewster_Panel       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Numero_Muestras_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_7                  matlab.ui.control.Label
        Min_Intensidad_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_6                  matlab.ui.control.Label
        Max_Intensidad_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_5                  matlab.ui.control.Label
        Ancho_Curva_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_4                  matlab.ui.control.Label
        Angulo_Medio_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_3                  matlab.ui.control.Label
        Indice_Refraccion_Brewster_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_2                  matlab.ui.control.Label
        Theta_R_Brewster_EditField      matlab.ui.control.NumericEditField
        Theta_RLabel                    matlab.ui.control.Label
        Ubicacion_Parmetros_Archivo_Experimental_Brewster_Panel  matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Factor_Suavizado_Brewster_Slider  matlab.ui.control.Slider
        FactordeSuavizadoSliderLabel    matlab.ui.control.Label
        Ubicacion_Archivo_Brewster_EditField  matlab.ui.control.EditField
        UbicacinEditFieldLabel          matlab.ui.control.Label
        Angulo_Inicial_Brewster_Spinner  matlab.ui.control.Spinner
        AvanceLabel_5                   matlab.ui.control.Label
        Angulo_Final_Brewster_Spinner   matlab.ui.control.Spinner
        AvanceLabel_4                   matlab.ui.control.Label
        Factor_Suavizado_Brewster_EditField  matlab.ui.control.NumericEditField
        Abrir_Archivo_Brewster_Button   matlab.ui.control.Button
        Analizar_Archivo_Brewster_Button  matlab.ui.control.Button
        Grafica_Experimental_Brewster_Axes  matlab.ui.control.UIAxes
        Abeles_Brewster_Tab             matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Resultados_Abeles_Panel         matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        Angulo_Minimo_Sustrato_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_12                 matlab.ui.control.Label
        Angulo_Minimo_Pelicula_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_11                 matlab.ui.control.Label
        Error_Experimental_Abeles_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_10                 matlab.ui.control.Label
        Indice_Refraccion_Pelicula_Abeles_EditField  matlab.ui.control.NumericEditField
        Theta_RLabel_9                  matlab.ui.control.Label
        Angulo_Cruce_Abeles_EditField   matlab.ui.control.NumericEditField
        Theta_RLabel_8                  matlab.ui.control.Label
        Ubicacion_Parmetros_Archivos_Experimentales_Abeles_Panel  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        Ubicacion_Archivo_Pelicula_Abeles_EditField  matlab.ui.control.EditField
        UbicacinSustratoLabel_2         matlab.ui.control.Label
        Factor_Suavizado_Abeles_Slider  matlab.ui.control.Slider
        FactordeSuavizadoSliderLabel_2  matlab.ui.control.Label
        Ubicacion_Archivo_Sustrato_Abeles_EditField  matlab.ui.control.EditField
        UbicacinSustratoLabel           matlab.ui.control.Label
        Angulo_Inicial_Abeles_Spinner   matlab.ui.control.Spinner
        AvanceLabel_7                   matlab.ui.control.Label
        Angulo_Final_Abeles_Spinner     matlab.ui.control.Spinner
        AvanceLabel_6                   matlab.ui.control.Label
        Calcular_Error_Abeles_Button    matlab.ui.control.Button
        Factor_Suavizado_Abeles_EditField  matlab.ui.control.NumericEditField
        Abrir_Archivo_Pelicula_Abeles_Button  matlab.ui.control.Button
        Abrir_Archivo_Sustrato_Abeles_Button  matlab.ui.control.Button
        Grafica_Experimental_Abeles_Axes  matlab.ui.control.UIAxes
        AjusteCurvasTab                 matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        Sistemas_Multicapa_Capas_Panel  matlab.ui.container.Panel
        Sistemas_Multicapa_n9_EditField  matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        Sistemas_Multicapa_n8_EditField  matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        Sistemas_Multicapa_n7_EditField  matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        Sistemas_Multicapa_n6_EditField  matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Sistemas_Multicapa_n5_EditField  matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Sistemas_Multicapa_n4_EditField  matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Sistemas_Multicapa_n3_EditField  matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Sistemas_Multicapa_n2_EditField  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Sistemas_Multicapa_n1_EditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Sistemas_Multicapa_d9_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d8_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d7_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d6_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d5_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d4_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d3_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d2_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_d1_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_dnm_Label    matlab.ui.control.Label
        Sistemas_Multicapa_k9_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k8_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k7_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k6_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k5_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k4_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k3_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k2_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k1_EditField  matlab.ui.control.NumericEditField
        Sistemas_Multicapa_k_Label      matlab.ui.control.Label
        Sistemas_Multicapa_n_Label      matlab.ui.control.Label
        Ubicacion_y_Parametros_Ajuste_Curvas_Panel  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Factor_Escala_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        FactordeEscalaLabel             matlab.ui.control.Label
        Offset_Vertical_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        OffsetVerticalSpinnerLabel      matlab.ui.control.Label
        Offset_Angular_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        OffsetLabel                     matlab.ui.control.Label
        Numero_Puntos_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        AvanceLabel_11                  matlab.ui.control.Label
        Longitud_Onda_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        AvanceLabel_10                  matlab.ui.control.Label
        Factor_Suavizado_Ajuste_Curvas_Slider  matlab.ui.control.Slider
        FactordeSuavizadoLabel          matlab.ui.control.Label
        Ubicacion_Archivo_Ajuste_Curvas_EditField  matlab.ui.control.EditField
        UbicacinSustratoLabel_3         matlab.ui.control.Label
        Angulo_Inicial_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        AvanceLabel_9                   matlab.ui.control.Label
        Angulo_Final_Ajuste_Curvas_Spinner  matlab.ui.control.Spinner
        AvanceLabel_8                   matlab.ui.control.Label
        Simular_Ajuste_Curvas_Button    matlab.ui.control.Button
        Factor_Suavizado_Ajuste_Curvas_EditField  matlab.ui.control.NumericEditField
        Abrir_Archivo_Ajuste_Curvas_Button  matlab.ui.control.Button
        Grafica_Ajuste_Curvas_Axes      matlab.ui.control.UIAxes
        DocumentacinTab                 matlab.ui.container.Tab
        HelpTextArea_5                  matlab.ui.control.TextArea
        HelpTextArea_4                  matlab.ui.control.TextArea
        HelpTextArea_3                  matlab.ui.control.TextArea
        HelpTextArea_2                  matlab.ui.control.TextArea
        Titulo_Label                    matlab.ui.control.Label
        HelpTextArea                    matlab.ui.control.TextArea
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        ArduinoPort
        Checkpoint_Theta = 0;
        Checkpoint_2Theta = 0;
        StopRequested = false;
        TurnDirection (1,1) uint16 = 0; % 2: CW, 1: CCW        

        ExpData_Angulos = [];
        ExpData_Intensidad = [];
    end

    methods (Access = private)

        % Función auxiliar: Actualizar Gráfica Comparativa
        function actualizarGraficaComparativa(app)
            ax = app.Grafica_Ajuste_Curvas_Axes;

            % Verificar si tenemos datos cargados
            if isempty(app.ExpData_Angulos) || isempty(app.ExpData_Intensidad)
                return;
            end

            hold(ax, 'on');

            % Recuperar datos originales
            angExp = app.ExpData_Angulos;
            intExp = app.ExpData_Intensidad;

            % 1. Aplicar Suavizado (Smoothing)
            try
                factorSmooth = round(app.Factor_Suavizado_Ajuste_Curvas_Slider.Value);
                if factorSmooth < 1, factorSmooth = 1; end
                intExpSuave = smoothdata(intExp, 'movmean', factorSmooth);
            catch
                intExpSuave = intExp;
            end

            % 2. Aplicar "Achaparrado" (Escalamiento / Ganancia) --> ¡LO NUEVO!
            try
                scaleFactor = app.Factor_Escala_Ajuste_Curvas_Spinner.Value;
                % Multiplicamos:
                % Si scaleFactor < 1 (ej. 0.8), la curva se "achaparra".
                % Si scaleFactor > 1 (ej. 1.2), la curva se estira.
                intExpEscalado = intExpSuave * scaleFactor;
            catch
                intExpEscalado = intExpSuave;
            end

            % 3. Aplicar Offset Vertical (Suma)
            try
                offsetY = app.Offset_Vertical_Ajuste_Curvas_Spinner.Value;
                % Sumamos el offset al dato ya escalado
                intExpFinal = intExpEscalado + offsetY;
            catch
                intExpFinal = intExpEscalado;
            end

            % 4. Aplicar Offset Angular (Eje X)
            try
                offsetX = app.Offset_Angular_Ajuste_Curvas_Spinner.Value;
                angExpFinal = angExp + offsetX;
            catch
                angExpFinal = angExp;
            end

            % 5. Graficar
            hVisual = findobj(ax, 'Tag', 'CurvaExperimentalVisual');

            if isempty(hVisual)
                plot(ax, angExpFinal, intExpFinal, 'b-', 'LineWidth', 1.5, ...
                    'DisplayName', 'Experimental (Ajustado)', 'Tag', 'CurvaExperimentalVisual');
            else
                hVisual.XData = angExpFinal;
                hVisual.YData = intExpFinal;
            end

            legend(ax, 'show', 'Location', 'best');
            grid(ax, 'on');
        end

        % Función TMM (Transfer Matrix Method) para Reflectancia
        function Rp = calcular_reflectancia_tmm(~, n_stack, d_layers_nm, lambda_nm, theta_deg)
            % Convertir ángulos a radianes
            theta_rad = deg2rad(theta_deg(:));
            num_angles = numel(theta_rad);
            num_layers = numel(n_stack);
            Rp = zeros(1, num_angles);
            
            % Número de onda en el vacío
            k0 = (2 * pi) / (lambda_nm * 1e-9);
            
            % Convertir grosores a metros
            d = d_layers_nm(:) * 1e-9;
            
            for a_idx = 1:num_angles
                theta_0 = theta_rad(a_idx);
                
                % Componente z del vector de onda (kz)
                % Ley de Snell generalizada para medios con pérdidas
                kz = k0 .* sqrt(n_stack.^2 - (n_stack(1) * sin(theta_0)).^2);
                
                % Convención de signos para absorción correcta
                kz(imag(kz) < 0) = -kz(imag(kz) < 0); 
                
                % Matriz del sistema
                M_p = eye(2);
                
                for j = 1:(num_layers - 1)
                    n1 = n_stack(j); 
                    n2 = n_stack(j+1);
                    kz1 = kz(j); 
                    kz2 = kz(j+1);
                    
                    % Coeficientes de Fresnel (Polarización p / TM)
                    denom_p = (n2^2 * kz1) + (n1^2 * kz2);
                    if abs(denom_p) < eps, denom_p = eps; end % Evitar div/0
                    
                    r_p = (n2^2 * kz1 - n1^2 * kz2) / denom_p;
                    t_p = (2 * n1 * n2 * kz1) / denom_p;
                    
                    % Matriz dinámica de la interfaz
                    D_p = (1 / t_p) * [1, r_p; r_p, 1];
                    M_p = M_p * D_p;
                    
                    % Matriz de propagación (si no es la última capa)
                    if j < (num_layers - 1)
                        delta = kz(j+1) * d(j);
                        P = [exp(-1i * delta), 0; 0, exp(1i * delta)];
                        M_p = M_p * P;
                    end
                end
                
                % Reflectancia total
                r_total_p = M_p(2,1) / M_p(1,1);
                Rp(a_idx) = abs(r_total_p)^2;
            end
        end
        
        % Función auxiliar para imprimir en monitor serial
        function logToMonitor(app, mensaje)
            timestamp = string(datetime('now', 'Format', 'HH:mm:ss'));

            % 2. Crear el mensaje nuevo
            textoNuevo = "[" + timestamp + "] " + mensaje;

            % 3. Obtener lo que ya había (asegurando que sea string, por si está vacío)
            logActual = string(app.Monitor_Serial_TextArea.Value);

            % 4. Concatenar:
            app.Monitor_Serial_TextArea.Value = [textoNuevo; logActual];
        end


        % Función para leer datos del ESP32 automáticamente
        function readSerialData(app, src, ~)
            try
                % 1. Leer línea del puerto serial
                if src.NumBytesAvailable > 0
                    dataLine = readline(src)
                    % dataLine
                    
                    % CASO 1: Lectura de Sensores (Respuesta a 'R' o lectura de fotodiodos)
                    if startsWith(dataLine, "SENS:")
                        try   % Try to read information from sensors
                            rawText = extractAfter(dataLine, "SENS:");
                            valores = split(rawText, ',');
                            
                            % A. Leer Voltajes Crudos (0 - 5V)
                            v1_raw = double(string(valores(1)));
                            
                            if length(valores) > 1
                                v2_raw = double(string(valores(2)));
                            else
                                v2_raw = 0;
                            end
                            
                            % B. APLICAR ESCALAMIENTO (VISUAL 0 - 10V)
                            factorEscala = 10.0 / 5; 
                            v1_scaled = v1_raw * factorEscala;
                            v2_scaled = v2_raw * factorEscala;
                            
                            % C. Actualizar la Interfaz
                            
                            % Sensor 1 (Muestra)
                            app.Lectura_Sensor_EditField.Value = v1_scaled;
                            % Slider con límite de seguridad de 12V
                            app.Lectura_Sensor_Slider.Value = min(max(v1_scaled, 0), 12);
                            try 
                                % Asumiendo que el límite de este slider es 10 o 12 igual que el otro
                                app.Lectura_Sensor_Duplicado_Slider.Value = min(max(v1_scaled, 0), 12); 
                            catch
                            end
                            
                            % Sensor 2 (Referencia)
                            logToMonitor(app, "Lectura: S1=" + num2str(v1_scaled, '%.3f') + "V | S2=" + num2str(v2_scaled, '%.2f') + "V");
                            
                        catch
                            logToMonitor(app, "Error procesando lectura sensor.");
                        end
                        
                    % CASO 2: Reporte de Posición
                    elseif startsWith(dataLine, "POS:")
                        try
                            rawText = regexprep(extractAfter(dataLine, "POS:"),"0Â°",'');
                            valores = split(rawText, ',');
                            app.Angulo_Actual_Mustra_EditField.Value = double(string(valores(1)));
                            app.Angulo_Actual_sensor_EditField.Value = double(string(valores(2)));
                        catch
                        end
                        
                    % CASO 3: Estado de Sensores Home
                    elseif startsWith(dataLine, "HOME:")
                        try
                            rawText = extractAfter(dataLine, "HOME:");
                            estados = split(rawText, ',');
                            app.MUESTRA_0_grados_CheckBox.Value = double(string(estados(1))) == 1;
                            app.SENSOR_0_grados_CheckBox.Value = double(string(estados(2))) == 1;
                            % If both motors are at Home, set turn direction to CW.
                            if all([double(string(estados(1))),double(string(estados(2)))])                       
                                app.TurnDirection = 2;  % If system was already homed, change home direction to CCW
                            %else
                            %    app.TurnDirection = 2;
                            end
                        catch
                        end
                        
                    % CASO 4: IDLE (El Arduino terminó)
                    elseif startsWith(dataLine, "IDLE")
                        app.Sistema_Operando_CheckBox.Value = false;
                        
                        % DESBLOQUEAR CONTROLES
                        toggleControlesManuales(app, 'on');
                        app.Ir_Home_Button.Enable = 'on';
                        
                        % RESTAURAR ESTILO VISUAL (FONDO BLANCO / TEXTO NEGRO)
                        app.Angulo_Actual_Mustra_EditField.BackgroundColor = [1 1 1]; 
                        app.Angulo_Actual_Mustra_EditField.FontColor = [0 0 0];       
                        
                        app.Angulo_Actual_sensor_EditField.BackgroundColor = [1 1 1]; 
                        app.Angulo_Actual_sensor_EditField.FontColor = [0 0 0];       
                        
                        logToMonitor(app, "Sistema Listo.");
                        
                    % CASO 5: Alertas
                    elseif startsWith(dataLine, "ALERTA:")
                        msg = extractAfter(dataLine, "ALERTA:");
                        uialert(app.UIFigure, msg, "Alerta", 'Icon','warning');
                        
                    % LOG GENÉRICO (Para depuración)
                    elseif strlength(dataLine) > 0
                    end
                end
            catch ME
                % disp("Error Serial: " + ME.message);
            end
        end

        % Función para bloquear/desbloquear controles manuales (Seguridad)
        function toggleControlesManuales(app, estado)
            % estado: 'on' (Habilitar todo) | 'off' (Bloquear todo menos Stop)

            % Lista de componentes a bloquear
            controles = { ...
                app.Ir_Inicio_Button, ...
                app.Guardar_Inicio_Button, ...
                app.Lectura_Sensor_Slider, ...
                app.SENSOR_0_grados_CheckBox, ...
                app.GIRO_MENOS_sensor_Button, ...
                app.GIRO_MAS_sensor_Button, ...
                app.Ir_Home_Button, ...
                app.MUESTRA_0_grados_CheckBox, ...
                app.Leer_Sensor_Button, ...
                app.Intensidad_Laser_Slider, ...
                app.Avance_Spinner, ...
                app.GIRO_MAS_muestra_Button, ...
                app.GIRO_MENOS_muestra_Button ...
                };

            % Aplicar estado
            for i = 1:length(controles)
                controles{i}.Enable = estado;
            end

            % Asegurar que el botón de detener motores siempre esté activo
            app.Detener_Motores_Button.Enable = 'on';

            % Cambio visual para indicar estado
            if strcmp(estado, 'off')
                app.Controles_Manuales_Automticos_Panel.Title = ' CONTROLES BLOQUEADOS (PROCESO EN CURSO)️';
            else
                app.Controles_Manuales_Automticos_Panel.Title = 'Controles Manuales y Automáticos';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.WindowState = 'maximized';
            toggleControlesManuales(app, 'off');
            try
                ports = serialportlist("available");

                if isempty(ports)
                    app.Puerto_DropDown.Items = "No se encontraron puertos";
                    app.Conectar_Button.Enable = 'off';
                else
                    app.Puerto_DropDown.Items = ports;
                    app.Conectar_Button.Enable = 'on';
                end
            catch
                app.Puerto_DropDown.Items = "Error buscando puertos";
            end

            app.Angulo_Actual_Mustra_EditField.Value = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.ArduinoPort) && isvalid(app.ArduinoPort)
                delete(app.ArduinoPort);
            end
            delete(app)
        end

        % Button pushed function: Conectar_Button
        function Conectar_ButtonPushed(app, event)
            selectedPort = app.Puerto_DropDown.Value;
            if selectedPort == "No se encontraron puertos" || selectedPort == "Error buscando puertos"
                return;
            end

            try  
                logToMonitor(app, "Intentando conexión con " + selectedPort + "...");
                app.ArduinoPort = serialport(selectedPort, 115200);
                configureTerminator(app.ArduinoPort, "LF");
                app.ArduinoPort.Timeout = 5;                

                %for idx = 1:10   %% Read serial port up to 10 times to validate if device is ESP32
                    %dataLine = readline(app.ArduinoPort);
                    %if startsWith(dataLine, "ESP32")
                        % UI Updates
                        app.Desconectar_Button.Enable = 'on';
                        app.Conectar_Button.Text = "Conectado";
                        app.Conectar_Button.BackgroundColor = [0, 1, 0];
                        app.Conectar_Button.Enable = 'off';
                        app.Puerto_DropDown.Enable = 'off';
                        app.Conexion_Establecida_CheckBox.Value = true;

                        % Limpiar y reportar
                        app.TurnDirection = 1;    % Set initial home direction to CW
                        app.Monitor_Serial_TextArea.Value = "";
                        logToMonitor(app, "Conexión Exitosa en " + selectedPort);
                        configureCallback(app.ArduinoPort, "terminator", @app.readSerialData);
                        toggleControlesManuales(app, 'on');
                        % writeline(app.ArduinoPort, "?");  % Redundant. Called in Arduino code at setup.
                        %break;
                    %end
                %end

                % if idx == 10
                %     disp("Puerto seleccionado no es ESP32.")
                %     error("MyApp:WrongESPStatus","ESP cannot initialize");
                % end        

            catch ME
                switch ME.identifier
                    case "MATLAB:string:MustBeCharCellArrayOrString"
                        message = "Timeout. El puerto seleccionado no está disponible";
                    case "serialport:serialport:ConnectionFailed"
                        message = "El sistema se encuentra en Home. Moverlo fuera de este estado manualmente.";
                    case "MyApp:WrongESPStatus"
                        message = "El sistema se encuentra en Home. Moverlo fuera de este estado manualmente y reiniciar el programa.";
                        uiwait(errordlg("Error al conectar: " + message, "Error de Conexión"));
                        SALIR_ButtonPushed(app, event);
                        return;
                    otherwise
                        message = "Error desconocido. ID: " + ME.identifier;
                end

                uialert(app.UIFigure, "Error al conectar: " + message, "Error de Conexión");
                app.Conexion_Establecida_CheckBox.Value = false;
                logToMonitor(app, "Fallo de conexión.");
            end
        end

        % Button pushed function: GIRO_MAS_muestra_Button
        function GIRO_MAS_muestra_ButtonPushed(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Primero conecta el sistema.", "Error");
                return;
            end

            grados = app.Avance_Spinner.Value;

            if grados == 0
                return;
            end

            comando = "M" + string(grados);

            try
                writeline(app.ArduinoPort, comando);
                % ACTIVAR CHECKBOX OPERANDO
                app.Sistema_Operando_CheckBox.Value = true;
                disp("Enviado: " + comando);
            catch
                uialert(app.UIFigure, "Error enviando datos.", "Error Serial");
            end
        end

        % Button pushed function: GIRO_MENOS_muestra_Button
        function GIRO_MENOS_muestra_ButtonPushed(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Primero conecta el sistema.", "Error");
                return;
            end

            grados = app.Avance_Spinner.Value;

            if grados == 0
                return;
            end

            comando = "M-" + string(grados);

            try
                writeline(app.ArduinoPort, comando);
                % ACTIVAR CHECKBOX OPERANDO
                app.Sistema_Operando_CheckBox.Value = true;
                disp("Enviado: " + comando);
            catch
                uialert(app.UIFigure, "Error enviando datos.", "Error Serial");
            end
        end

        % Button pushed function: Desconectar_Button
        function Desconectar_ButtonPushed(app, event)
            if ~isempty(app.ArduinoPort) && isvalid(app.ArduinoPort)
                delete(app.ArduinoPort);
                app.ArduinoPort = [];
            end

            % Restaurar botones
            app.Conectar_Button.Enable = 'on';
            app.Conectar_Button.Text = 'Conectar';
            app.Conectar_Button.BackgroundColor = [0.24, 0.35, 0.20];
            app.Puerto_DropDown.Enable = 'on';
            app.Desconectar_Button.Enable = 'off';
            app.Desconectar_Button.BackgroundColor = [0.55, 0.16, 0.16];

            % DESACTIVAR CHECKBOXES
            app.Conexion_Establecida_CheckBox.Value = false;
            app.Sistema_Operando_CheckBox.Value = false;

            disp("Sistema Desconectado. Puerto COM liberado.");
        end

        % Button pushed function: Detener_Motores_Button
        function Detener_Motores_ButtonPushed(app, event)
            % 1. Levantar la bandera lógica para romper bucles de MATLAB
            app.StopRequested = true;
            toggleControlesManuales(app, 'on');

            % 2. Enviar comando físico al Arduino
            if ~isempty(app.ArduinoPort) && isvalid(app.ArduinoPort)
                try
                    writeline(app.ArduinoPort, "S");
                    logToMonitor(app, "TX: S (PARADA DE EMERGENCIA)");
                catch
                end
            end
        end

        % Button pushed function: SALIR_Button
        function SALIR_ButtonPushed(app, event)
            % 1. Cerrar conexión serial si existe
            if ~isempty(app.ArduinoPort) && isvalid(app.ArduinoPort)
                writeline(app.ArduinoPort, "U"); % Un-home device to ensure next connection
                pause(500e-3);
                delete(app.ArduinoPort);
            end

            % 2. Cerrar la aplicación completa
            delete(app);
        end

        % Value changed function: Intensidad_Laser_Slider
        function Intensidad_Laser_SliderValueChanged(app, event)
            % Valor deseado por el usuario (0 a 5V)
            valorDeseado = app.Intensidad_Laser_Slider.Value; 
            app.Valor_Laser_EditField.Value = valorDeseado;
            
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort), return; end
            
            % Conversión: 
            % 5V (usuario) -> 255 (PWM máximo)
            % 0V (usuario) -> 0   (PWM mínimo)
            pwmValue = round((valorDeseado / 5.0) * 255);
            
            comando = "L" + string(pwmValue);
            try writeline(app.ArduinoPort, comando); catch; end
        end

        % Value changed function: Valor_Laser_EditField
        function Valor_Laser_EditFieldValueChanged(app, event)
            % 1. Leer el valor que escribió el usuario
            val = app.Valor_Laser_EditField.Value;

            % 2. Seguridad: Limitar entre 0 y 3.5
            if val < 0
                val = 0;
            elseif val > 3.5
                val = 3.5;
            end

            % 3. Actualizar la interfaz
            app.Valor_Laser_EditField.Value = val; % Escribe el valor corregido
            app.Intensidad_Laser_Slider.Value = val; % Mueve el slider visualmente

            % 4. ENVIAR AL ESP32
            Intensidad_Laser_SliderValueChanged(app, event);
        end

        % Button pushed function: Ir_Home_Button
        function Ir_Home_ButtonPushed(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Desconectado.", "Error"); return;
            end

            msg = "Se calibrarán AMBOS motores. ¿El camino está libre?";
            selection = uiconfirm(app.UIFigure, msg, "Homing Dual", ...
                'Options', {'Cancelar', 'Iniciar'}, 'DefaultOption', 2, 'Icon', 'warning');

            if strcmp(selection, 'Cancelar'), return; end

            try
               % Write Home command here, currently adapted to Ard v2.3
               % #TODO: add logic to move on other direction (2) after sweep.                
                writeline(app.ArduinoPort, "F,"+ num2str(app.TurnDirection));
                app.Sistema_Operando_CheckBox.Value = true;

                % 1. Bloquear controles manuales
                toggleControlesManuales(app, 'off');

                % 2. EXCEPCIÓN: Mantener los indicadores de ángulo HABILITADOS (Negro)
                app.Angulo_Actual_Mustra_EditField.Enable = 'on';
                app.Angulo_Actual_sensor_EditField.Enable = 'on';

                % 3. Feedback visual inmediato: Ponerlos en 0
                app.Angulo_Actual_Mustra_EditField.Value = 0;
                app.Angulo_Actual_sensor_EditField.Value = 0;

                logToMonitor(app, "TX: F (Iniciando Calibración Dual)");
            catch
                logToMonitor(app, "Error al enviar comando Home.");
            end
        end

        % Button pushed function: GIRO_MAS_sensor_Button
        function GIRO_MAS_sensor_ButtonPushed(app, event)
            % 1. Verificar Conexión
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Primero conecta el sistema.", "Error");
                return;
            end

            % 2. Leer Grados
            grados = app.Avance_Spinner.Value;
            if grados == 0, return; end

            % 3. Construir Comando 'N'
            comando = "N" + string(grados);

            try
                writeline(app.ArduinoPort, comando);
                app.Sistema_Operando_CheckBox.Value = true;
                logToMonitor(app, "TX: " + comando + " (Giro Sensor +)");
            catch
                logToMonitor(app, "Error enviando comando al Sensor.");
            end
        end

        % Button pushed function: GIRO_MENOS_sensor_Button
        function GIRO_MENOS_sensor_ButtonPushed2(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Primero conecta el sistema.", "Error");
                return;
            end

            grados = app.Avance_Spinner.Value;
            if grados == 0, return; end

            comando = "N-" + string(grados); % Negativo

            try
                writeline(app.ArduinoPort, comando);
                app.Sistema_Operando_CheckBox.Value = true;
                logToMonitor(app, "TX: " + comando + " (Giro Sensor -)");
            catch
                logToMonitor(app, "Error enviando comando al Sensor.");
            end
        end

        % Button pushed function: Leer_Sensor_Button
        function Leer_Sensor_ButtonPushed(app, event)
            % 1. Verificar Conexión
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Primero conecta el sistema.", "Error");
                return;
            end

            % 2. Enviar comando 'R' (Read)
            try
                writeline(app.ArduinoPort, "R");
            catch
                logToMonitor(app, "Error pidiendo lectura.");
            end
        end

        % Button pushed function: Guardar_Inicio_Button
        function Guardar_Inicio_ButtonPushed(app, event)
            % 1. Guardar los valores actuales de los EditFields
            app.Checkpoint_Theta = app.Angulo_Actual_Mustra_EditField.Value;
            app.Checkpoint_2Theta = app.Angulo_Actual_sensor_EditField.Value;

            % 2. Avisar en el Monitor Serial (Log)
            msg = "Punto Guardado: Muestra=" + string(app.Checkpoint_Theta) + ...
                "°, Sensor=" + string(app.Checkpoint_2Theta) + "°";
            logToMonitor(app, msg);

            % 3. Feedback visual rápido
            app.Guardar_Inicio_Button.Text = "¡Guardado!";
            pause(0.5);
            app.Guardar_Inicio_Button.Text = "Guardar Inicio";
        end

        % Button pushed function: Ir_Inicio_Button
        function Ir_Inicio_ButtonPushed(app, event)
            % Verificar conexión
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "No hay conexión.", "Error");
                return;
            end

            % 1. Calcular cuánto nos falta para llegar (Diferencia)
            % Fórmula: Delta = Destino - Actual
            deltaMuestra = app.Checkpoint_Theta - app.Angulo_Actual_Mustra_EditField.Value;
            deltaSensor = app.Checkpoint_2Theta - app.Angulo_Actual_sensor_EditField.Value;

            % 2. Validar si ya estamos ahí (para no mandar comandos basura)
            if abs(deltaMuestra) < 0.01 && abs(deltaSensor) < 0.01
                logToMonitor(app, "Ya estás en el punto de inicio.");
                return;
            end

            % 3. Enviar comandos de movimiento relativo
            % Mover Muestra (Si hace falta)
            if abs(deltaMuestra) >= 0.005
                cmdX = "M" + string(deltaMuestra);
                writeline(app.ArduinoPort, cmdX);
                pause(0.1); % Pequeña pausa para no saturar buffer
            end

            % Mover Sensor (Si hace falta)
            if abs(deltaSensor) >= 0.005
                cmdY = "N" + string(deltaSensor);
                writeline(app.ArduinoPort, cmdY);
            end

            logToMonitor(app, "Regresando a punto de inicio");
            app.Sistema_Operando_CheckBox.Value = true;
        end

        % Button pushed function: Iniciar_Barrido_Button
        function Iniciar_Barrido_ButtonPushed(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Desconectado.", "Error"); return;
            end

            % 1. BLOQUEAR CONTROLES
            toggleControlesManuales(app, 'off');

            % Leer Parámetros
            angInicio = app.Angulo_Actual_Mustra_EditField.Value;
            angFinal = app.Angulo_Final_Barrido_Spinner.Value;
            gradosMover = angFinal - angInicio;

            if gradosMover <= 0
                uialert(app.UIFigure, "Ángulo final inválido.", "Error");
                toggleControlesManuales(app, 'on');
                return;
            end

            % Leer Resolución
            strRes = app.Resolucion_Barrido_DropDown.Value;
            if contains(strRes, "0.01"), resolucion = 0.01;
            elseif contains(strRes, "0.1"), resolucion = 0.1;
            else, resolucion = 1.0; 
            end

            % Preparar Interfaz
            app.Iniciar_Barrido_Button.Enable = 'off';
            app.Iniciar_Barrido_Button.Text = "BARRIDO EN CURSO...";
            app.StopRequested = false;

            configureCallback(app.ArduinoPort, "off");

            % Limpieza
            cla(app.Grafica_Barrido_Angular_Axes);
            hold(app.Grafica_Barrido_Angular_Axes, 'on');
            flush(app.ArduinoPort);

            % Enviar Comando
            cmd = "B," + string(gradosMover) + "," + string(resolucion);
            writeline(app.ArduinoPort, cmd);
            logToMonitor(app, ">>> Iniciando Barrido Angular...");

            % Vectores crudos
            rawAng = [];
            rawV1 = [];
            rawV2 = [];

            % Control de refresco visual
            puntosAcumulados = 0;
            updateThreshold = 20; % Actualizar cada 20 datos

            % BUCLE DE ADQUISICIÓN
            while true
                if app.StopRequested, writeline(app.ArduinoPort, "S")
                    break; 
                end

                try
                    if app.ArduinoPort.NumBytesAvailable > 0
                        linea = readline(app.ArduinoPort);
                        linea = strtrim(linea);

                        if strcmp(linea, "END_BARRIDO")
                            % If sweep completed, set home direction to CCW.
                            app.TurnDirection = 2;
                            break; 
                        end

                        if strcmp(linea, "STOP_BARRIDO") 
                            break; 
                        end
                        
                        if strcmp(linea, "START_BARRIDO") 
                            continue; 
                        end

                        datos = split(linea, ',');

                        if length(datos) >= 3
                            a = double(string(datos(1)));

                            v1_raw = double(string(datos(2)));
                            v2_raw = double(string(datos(3)));
                            
                            % APLICAMOS ESCALAMIENTO (Para mostrar 0-10V)
                            factorEscala = 10.0 / 5; 
                            
                            v1 = v1_raw * factorEscala;
                            v2 = v2_raw * factorEscala;

                            if ~isnan(a)
                                rawAng(end+1) = a;
                                rawV1(end+1) = v1;
                                rawV2(end+1) = v2;
                                puntosAcumulados = puntosAcumulados + 1;

                                %% VISUALIZACIÓN EN TIEMPO REAL
                                % Updates Grafica_Barrido_Angular_Axes
                                % when the accumalted points is equal to updateThreshold
                                if puntosAcumulados >= updateThreshold
                                    puntosAcumulados = 0;

                                    % 1. Limpieza rápida para visualización
                                    [uAng, idx] = unique(rawAng);

                                    if length(uAng) > 5
                                        uV1 = rawV1(idx);
                                        uV2 = rawV2(idx);

                                        % Definir rango visual actual
                                        maxAngActual = floor(max(uAng) / resolucion) * resolucion;
                                        if maxAngActual > angFinal, maxAngActual = angFinal; end

                                        vecVisual = angInicio : resolucion : maxAngActual; % vecVisual: angle vector

                                        if length(vecVisual) > 1   % Start of interpolation routine
                                            % Interpolación visual suave
                                            v1_vis = interp1(uAng, smoothdata(uV1, 'movmean', 5), vecVisual, 'linear', 'extrap');
                                            v2_vis = interp1(uAng, smoothdata(uV2, 'movmean', 5), vecVisual, 'linear', 'extrap');

                                            % Graficar
                                            plot(app.Grafica_Barrido_Angular_Axes, vecVisual, v1_vis, 'b-', 'LineWidth', 1.5);
                                            plot(app.Grafica_Barrido_Angular_Axes, vecVisual, v2_vis, 'r-', 'LineWidth', 1.5);

                                            % ACTUALIZACIÓN DE INDICADORES NUMÉRICOS
                                            valAng = rawAng(end);

                                            % 1. Slider (Sensor 1)
                                            app.Lectura_Sensor_Duplicado_Slider.Value = min(max(v1, 0), 3.5);
                                            % #TODO: Check 3.5 as minimum value                                            % value
                                            app.Lectura_Sensor_EditField.Value = v1;

                                            % 2. Indicadores de Ángulo (Muestra y Sensor)
                                            app.Angulo_Actual_Mustra_EditField.Value = valAng;       % Theta
                                            app.Angulo_Actual_sensor_EditField.Value = valAng * 2;   % 2-Theta

                                            drawnow limitrate;
                                        end % end of interpolation routine
                                    end
                                end
                            end
                        end
                    end
                catch
                    % #TODO: add catch code in case no data received in
                    % serial port
                    % uialert(app.UIFigure, "Datos no recibidos desde el puerto.", "Cancelar");
                end
            end  % End of acquisition while loop

            % PROCESAMIENTO FINAL Y GUARDADO
            if ~isempty(rawAng) && ~app.StopRequested
                try
                    % 1. Limpieza de datos crudos (Eliminar duplicados)
                    [uniqueAng, idx] = unique(rawAng);
                    uniqueV1 = rawV1(idx);
                    uniqueV2 = rawV2(idx);
                    
                    % 2. GENERAR EL VECTOR MAESTRO (Aquí nace 'angulosFinales')
                    angulosFinales = angInicio : resolucion : angFinal;
                    
                    % 3. Interpolación para regularizar los datos (Aquí nacen v1_final y v2_final)
                    v1_final = interp1(uniqueAng, uniqueV1, angulosFinales, 'linear', 'extrap');
                    v2_final = interp1(uniqueAng, uniqueV2, angulosFinales, 'linear', 'extrap');
                    
                    % 4. Gráfica Final Estática (Limpia y bonita)
                    cla(app.Grafica_Barrido_Angular_Axes);
                    plot(app.Grafica_Barrido_Angular_Axes, angulosFinales, v1_final, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Muestra');
                    plot(app.Grafica_Barrido_Angular_Axes, angulosFinales, v2_final, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Ref');
                    legend(app.Grafica_Barrido_Angular_Axes, 'Location', 'best');
                    grid(app.Grafica_Barrido_Angular_Axes, 'on');
                    
                    % 5. Guardar Archivo
                    datosGuardar = [angulosFinales', v1_final', v2_final'];
                    
                    % Lógica de ruta al Escritorio
                    userProfile = getenv('USERPROFILE'); 
                    if isempty(userProfile), userProfile = getenv('HOME'); end
                    
                    rutaData = fullfile(userProfile, 'Desktop', 'DATA');
                    if ~exist(rutaData, 'dir'), mkdir(rutaData); end
                    
                    timestamp = string(datetime('now','Format','dd-MM-yyyy_HH-mm-ss'));
                    nombre = string(resolucion) + "deg_" + timestamp + ".dat";
                    archivoFinal = fullfile(rutaData, nombre);
                    
                    writematrix(datosGuardar, archivoFinal, 'Delimiter', '\t');
                    % #TODO: review if is better to use ',' as delimeter to
                    % save as CSV file.
                    
                    logToMonitor(app, ">> Archivo guardado: " + nombre);
                    uialert(app.UIFigure, "Barrido finalizado correctamente.", "Éxito");
                    
                catch ME
                    uialert(app.UIFigure, "Error procesando datos finales: " + ME.message, "Error de Datos");
                end
            end

            % RESTAURAR
            toggleControlesManuales(app, 'on');
            configureCallback(app.ArduinoPort, "terminator", @app.readSerialData);

            app.Iniciar_Barrido_Button.Enable = 'on';
            app.Iniciar_Barrido_Button.Text = "INICIAR BARRIDO ANGULAR";
            writeline(app.ArduinoPort, "?");
        end

        % Button pushed function: Analizar_Archivo_Brewster_Button
        function Analizar_Archivo_Brewster_ButtonPushed(app, event)
            % 1. Obtener la ruta
            filePath = app.Ubicacion_Archivo_Brewster_EditField.Value;

            % Validar
            if isempty(filePath) || ~isfile(filePath)
                uialert(app.UIFigure, "Seleccione un archivo válido.", "Error");
                return;
            end

            try
                % 2. Cargar Datos
                data = readmatrix(filePath);
                angulos_raw = data(:, 1);
                intensidades_raw = data(:, 2);

                % 3. Filtrar por Rango
                angInicio = app.Angulo_Inicial_Brewster_Spinner.Value;
                angFinal = app.Angulo_Final_Brewster_Spinner.Value;

                mask = (angulos_raw >= angInicio) & (angulos_raw <= angFinal);
                angulos = angulos_raw(mask);
                intensidades = intensidades_raw(mask);

                if isempty(angulos)
                    uialert(app.UIFigure, "Rango vacío.", "Error");
                    return;
                end

                % 4. Suavizado
                factorSuavizado = round(app.Factor_Suavizado_Brewster_Slider.Value);
                intensidad_suave = smoothdata(intensidades, 'movmean', factorSuavizado);

                % 5. Cálculos
                [minIntensidadSmooth, idxMin] = min(intensidad_suave);
                theta_R = angulos(idxMin);
                indice_n = tan(deg2rad(theta_R));

                % Estadísticas
                maxIntensidad = max(intensidades);
                minIntensidad = min(intensidades);
                numMuestras = length(angulos);
                anguloMedio = mean(angulos);
                anchoCurva = angulos(end) - angulos(1);

                % 6. Mostrar Resultados
                app.Theta_R_Brewster_EditField.Value = theta_R;
                app.Indice_Refraccion_Brewster_EditField.Value = indice_n;
                app.Angulo_Medio_Brewster_EditField.Value = anguloMedio;
                app.Ancho_Curva_Brewster_EditField.Value = anchoCurva;
                app.Max_Intensidad_Brewster_EditField.Value = maxIntensidad;
                app.Min_Intensidad_Brewster_EditField.Value = minIntensidad;
                app.Numero_Muestras_Brewster_EditField.Value = numMuestras;

                % 7. Graficar
                ax = app.Grafica_Experimental_Brewster_Axes;
                cla(ax);
                hold(ax, 'on');

                plot(ax, angulos, intensidades, '.', 'Color', [0.6 0.6 0.6], 'DisplayName', 'Datos');
                plot(ax, angulos, intensidad_suave, '-', 'Color', [0 0.45 0.74], 'LineWidth', 2, 'DisplayName', 'Ajuste');
                plot(ax, theta_R, minIntensidadSmooth, 'o', 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k', 'MarkerSize', 8, 'DisplayName', 'Mínimo');

                title(ax, 'Análisis de Brewster');
                xlabel(ax, 'Ángulo (°)');
                ylabel(ax, 'Intensidad');
                grid(ax, 'on');

                % 8. AJUSTE DINÁMICO DE ESCALAS

                ax.XLim = [angInicio, angFinal];

                rangoY = maxIntensidad - minIntensidad;
                if rangoY == 0, rangoY = 1; end % Evitar error si es línea plana
                margin = rangoY * 0.05;

                % Forzamos los límites Y para hacer acercamiento a la curva de interés
                ax.YLim = [minIntensidad - margin, maxIntensidad + margin];

                hold(ax, 'off');

            catch ME
                uialert(app.UIFigure, "Error: " + ME.message, "Error");
            end
        end

        % Button pushed function: Abrir_Archivo_Brewster_Button
        function Abrir_Archivo_Brewster_ButtonPushed(app, event)
            % Abre el explorador de archivos
            [file, path] = uigetfile({'*.dat';'*.txt';'*.*'}, 'Seleccionar Archivo Experimental');

            % Si el usuario selecciona un archivo
            if ~isequal(file, 0)
                fullPath = fullfile(path, file);
                % Actualizamos el EditField con el nombre correcto
                app.Ubicacion_Archivo_Brewster_EditField.Value = fullPath;
            end
        end

        % Button pushed function: Abrir_Archivo_Sustrato_Abeles_Button
        function Abrir_Archivo_Sustrato_Abeles_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.dat';'*.txt';'*.*'}, 'Seleccionar Sustrato');
            if ~isequal(file, 0)
                app.Ubicacion_Archivo_Sustrato_Abeles_EditField.Value = fullfile(path, file);
            end
        end

        % Button pushed function: Abrir_Archivo_Pelicula_Abeles_Button
        function Abrir_Archivo_Pelicula_Abeles_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.dat';'*.txt';'*.*'}, 'Seleccionar Película');
            if ~isequal(file, 0)
                app.Ubicacion_Archivo_Pelicula_Abeles_EditField.Value = fullfile(path, file);
            end
        end

        % Button pushed function: Calcular_Error_Abeles_Button
        function Calcular_Error_Abeles_ButtonPushed(app, event)
            % 1. Validaciones
            fileS = app.Ubicacion_Archivo_Sustrato_Abeles_EditField.Value;
            fileP = app.Ubicacion_Archivo_Pelicula_Abeles_EditField.Value;
            
            if isempty(fileS) || isempty(fileP)
                uialert(app.UIFigure, "Cargue ambos archivos (Sustrato y Película).", "Faltan Datos");
                return;
            end
            
            try
                % 2. Cargar Datos
                dataS = readmatrix(fileS);
                thetaS = dataS(:,1); intS = dataS(:,2);
                
                dataP = readmatrix(fileP);
                thetaP = dataP(:,1); intP = dataP(:,2);
                
                % 3. Filtrar por Rango
                angMin = app.Angulo_Inicial_Abeles_Spinner.Value;
                angMax = app.Angulo_Final_Abeles_Spinner.Value;
                
                % Filtrar Sustrato
                maskS = (thetaS >= angMin) & (thetaS <= angMax);
                tS = thetaS(maskS); iS = intS(maskS);
                
                % Filtrar Película
                maskP = (thetaP >= angMin) & (thetaP <= angMax);
                tP = thetaP(maskP); iP = intP(maskP);
                
                if isempty(tS) || isempty(tP)
                    uialert(app.UIFigure, "El rango seleccionado no contiene datos.", "Error de Rango");
                    return;
                end
                
                % 4. Suavizado
                factor = round(app.Factor_Suavizado_Abeles_Slider.Value); 
                iS_smooth = smoothdata(iS, 'movmean', factor);
                iP_smooth = smoothdata(iP, 'movmean', factor);
                
                % 5. ANÁLISIS 1: Mínimos Individuales
                [minValS, idxS] = min(iS_smooth);
                brewsterS = tS(idxS); % Mínimo del Sustrato
                
                [minValP, idxP] = min(iP_smooth);
                brewsterP = tP(idxP); % Mínimo de la Película
                
                % 6. ANÁLISIS 2: BÚSQUEDA DE MÚLTIPLES CRUCES (ABELÈS)
                ejeComun = linspace(max(min(tS), min(tP)), min(max(tS), max(tP)), 2000);
                
                yS_interp = interp1(tS, iS_smooth, ejeComun, 'pchip');
                yP_interp = interp1(tP, iP_smooth, ejeComun, 'pchip');
                
                % Restamos las curvas. Un cruce ocurre cuando la diferencia cambia de signo.
                diffSign = sign(yS_interp - yP_interp);
                % Buscamos dónde la diferencia entre signos adyacentes no es cero
                cruces_idx = find(diff(diffSign) ~= 0); 
                
                if isempty(cruces_idx)
                    uialert(app.UIFigure, "No se encontraron cruces en este rango angular.", "Advertencia");
                    return;
                end
                
                % Refinar la posición de los cruces (interpolación lineal local para más precisión)
                theta_cruces = zeros(length(cruces_idx), 1);
                int_cruces = zeros(length(cruces_idx), 1);
                indices_n_calculados = zeros(length(cruces_idx), 1);
                
                opciones_cruce = string(zeros(length(cruces_idx), 1)); % Para el menú desplegable
                
                for k = 1:length(cruces_idx)
                    idx = cruces_idx(k);
                    % Tomamos los dos puntos alrededor del cambio de signo
                    x1 = ejeComun(idx); x2 = ejeComun(idx+1);
                    y1_diff = yS_interp(idx) - yP_interp(idx);
                    y2_diff = yS_interp(idx+1) - yP_interp(idx+1);
                    
                    % Interpolación lineal para encontrar la raíz exacta (donde diff == 0)
                    m = (y2_diff - y1_diff) / (x2 - x1);
                    x_zero = x1 - (y1_diff / m);
                    
                    theta_cruces(k) = x_zero;
                    % Estimamos la intensidad en el cruce interpolando en yS
                    int_cruces(k) = interp1(ejeComun, yS_interp, x_zero, 'linear'); 
                    indices_n_calculados(k) = tan(deg2rad(x_zero));
                    
                    % Crear texto para el menú
                    opciones_cruce(k) = sprintf("Cruce %d: %.2f° (n = %.3f)", k, x_zero, indices_n_calculados(k));
                end
                
                % 7. SELECCIÓN DEL CRUCE (SI HAY MÁS DE UNO)
                idxSeleccionado = 1; % Por defecto el primero
                
                if length(theta_cruces) > 1
                    % Mostrar un diálogo para que el usuario elija
                    [seleccion, ok] = listdlg('PromptString', 'Se detectaron múltiples cruces. Seleccione el cruce físico válido:', ...
                                              'SelectionMode', 'single', ...
                                              'ListString', opciones_cruce, ...
                                              'Name', 'Selección de Cruce de Abelès', ...
                                              'ListSize', [250 100]);
                    if ok
                        idxSeleccionado = seleccion;
                    else
                         % Si el usuario cancela, usamos el primero, pero avisamos.
                        disp("Selección cancelada, usando el primer cruce por defecto.");
                    end
                end
                
                thetaAbeles_final = theta_cruces(idxSeleccionado);
                intAbeles_final = int_cruces(idxSeleccionado);
                indice_n_final = indices_n_calculados(idxSeleccionado);

                % 8. RESULTADOS FÍSICOS FINALES
                error_grados = abs(brewsterP - thetaAbeles_final);
                
                % 9. MOSTRAR EN LA UI
                % Angulo Mínimo Sustrato
                app.Angulo_Minimo_Sustrato_EditField.Value = brewsterS;
                
                % Angulo Mínimo Película
                app.Angulo_Minimo_Pelicula_EditField.Value = brewsterP;
                
                % Ángulo de Cruce (Abelès) Seleccionado
                app.Angulo_Cruce_Abeles_EditField.Value = thetaAbeles_final;
                
                % Índice de Refracción Calculado (Película)
                app.Indice_Refraccion_Pelicula_Abeles_EditField.Value = indice_n_final;
                
                % Error Experimental
                app.Error_Experimental_Abeles_EditField.Value = error_grados;
                
                % 10. GRAFICAR
                ax = app.Grafica_Experimental_Abeles_Axes;
                cla(ax); hold(ax, 'on');
                
                plot(ax, tS, iS_smooth, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Sustrato');
                plot(ax, tP, iP_smooth, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Película');
                
                % Marcadores de mínimos
                plot(ax, brewsterS, minValS, 'bo', 'HandleVisibility', 'off');
                plot(ax, brewsterP, minValP, 'ro', 'HandleVisibility', 'off');
                
                % Graficar TODOS los cruces en gris tenue
                plot(ax, theta_cruces, int_cruces, 'x', 'MarkerSize', 8, 'Color', [0.7 0.7 0.7], 'HandleVisibility', 'off');
                
                % Resaltar el CRUCE SELECCIONADO (Estrella Verde)
                plot(ax, thetaAbeles_final, intAbeles_final, 'p', 'MarkerSize', 14, ...
                    'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'k', ...
                    'DisplayName', sprintf('Cruce Seleccionado: %.2f°', thetaAbeles_final));
                
                title(ax, 'Método de Abelès');
                xlabel(ax, 'Ángulo (°)'); ylabel(ax, 'Intensidad');
                legend(ax, 'Location', 'best');
                grid(ax, 'on');
                
                % Zoom automático
                ax.XLim = [angMin, angMax];
                ax.YLim = [min(min(iS), min(iP))*0.9, max(max(iS), max(iP))*1.1];
                
                hold(ax, 'off');
                
                % 9. GRAFICAR
                ax = app.Grafica_Experimental_Abeles_Axes;
                cla(ax); hold(ax, 'on');
                
                plot(ax, tS, iS_smooth, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Sustrato');
                plot(ax, tP, iP_smooth, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Película');
                
                % Marcadores
                plot(ax, brewsterS, minValS, 'bo', 'HandleVisibility', 'off');
                plot(ax, brewsterP, minValP, 'ro', 'HandleVisibility', 'off');
                
                % CRUCE ABELÈS (Estrella Verde)
                % plot(ax, thetaAbeles, intAbeles, 'p', 'MarkerSize', 14, ...
                %    'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'k', ...
                %    'DisplayName', ['Cruce: ' num2str(thetaAbeles, '%.2f') '°']);
                
                title(ax, 'Método de Abelès');
                xlabel(ax, 'Ángulo (°)'); ylabel(ax, 'Intensidad');
                legend(ax, 'Location', 'best');
                grid(ax, 'on');
                
                % Zoom automático
                ax.XLim = [angMin, angMax];
                ax.YLim = [min(min(iS), min(iP))*0.9, max(max(iS), max(iP))*1.1];
                
                hold(ax, 'off');
                
            catch ME
                uialert(app.UIFigure, "Error en cálculo: " + ME.message, "Error");
            end
        end

        % Button pushed function: Iniciar_Medicion_Button
        function Iniciar_Medicion_ButtonPushed(app, event)
            if isempty(app.ArduinoPort) || ~isvalid(app.ArduinoPort)
                uialert(app.UIFigure, "Desconectado.", "Error"); return;
            end
            
            % 1. Configuración
            tiempoTotal = app.Tiempo_Total_Spinner.Value;
            
            % Interpretar tiempo de muestreo del DropDown
            strMuestreo = app.Tiempo_Muestreo_DropDown.Value;
            if contains(strMuestreo, "10 ms"), tPause = 0.01;
            elseif contains(strMuestreo, "100 ms"), tPause = 0.1;
            else, tPause = 1.0; 
            end
            
            % Preparar UI
            app.Iniciar_Medicion_Button.Enable = 'off';
            app.Iniciar_Medicion_Button.Text = "MIDIENDO...";
            app.StopRequested = false;
            
            cla(app.Grafica_Angulo_Fijo_Axes);
            hold(app.Grafica_Angulo_Fijo_Axes, 'on');
            
            % Vectores
            timeVec = [];
            voltVec1 = [];
            voltVec2 = [];
            
            timerStart = tic;
            
            % 2. Bucle de Tiempo
            while toc(timerStart) <= tiempoTotal
                if app.StopRequested, break; end
                
                try
                    % Petición de lectura
                    writeline(app.ArduinoPort, "R");
                    
                    % Leer respuesta
                    linea = readline(app.ArduinoPort);
                    if startsWith(linea, "SENS:")
                        rawText = extractAfter(linea, "SENS:");
                        vals = split(rawText, ',');
                        
                        v1_raw = double(string(vals(1)));
                        if length(vals) > 1
                            v2_raw = double(string(vals(2))); 
                        else 
                            v2_raw = 0; 
                        end
                        
                        % ESCALAMIENTO 0-10V
                        factor = 10.0 / 3.3;
                        v1 = v1_raw * factor;
                        v2 = v2_raw * factor;
                        
                        % Guardar
                        tActual = toc(timerStart);
                        timeVec(end+1) = tActual;
                        voltVec1(end+1) = v1;
                        voltVec2(end+1) = v2;
                        
                        % Graficar en vivo
                        if mod(length(timeVec), 5) == 0
                            plot(app.Grafica_Angulo_Fijo_Axes, timeVec, voltVec1, 'b-', 'LineWidth', 1.5);
                            plot(app.Grafica_Angulo_Fijo_Axes, timeVec, voltVec2, 'r-', 'LineWidth', 1.5);
                            drawnow limitrate;
                        end
                    end
                    
                    pause(tPause); % Espera según muestreo seleccionado
                catch
                end
            end
            
            % 3. Guardado Automático
            if ~isempty(timeVec)
                try
                    datos = [timeVec', voltVec1', voltVec2'];
                    
                    % RUTA AL ESCRITORIO
                    % Detecta la carpeta de usuario (Ej: C:\Users\Oscar)
                    userProfile = getenv('USERPROFILE'); 
                    if isempty(userProfile)
                         % Fallback por si no detecta (raro en Windows)
                        userProfile = getenv('HOME'); 
                    end
                    
                    % Construye la ruta: C:\Users\Oscar\Desktop\DATA
                    rutaData = fullfile(userProfile, 'Desktop', 'DATA');
                    
                    % Si no existe, la crea
                    if ~exist(rutaData, 'dir'), mkdir(rutaData); end
                    % ------------------------------------
                    
                    nombre = "Tiempo_" + string(datetime('now','Format','dd-MM-yyyy_HH-mm-ss')) + ".dat";
                    archivoFinal = fullfile(rutaData, nombre);
                    
                    writematrix(datos, archivoFinal, 'Delimiter', '\t');
                    logToMonitor(app, "Guardado en Escritorio/DATA: " + nombre);
                    
                catch ME
                    uialert(app.UIFigure, "Error guardando: " + ME.message, "Error");
                end
            end
            
            app.Iniciar_Medicion_Button.Enable = 'on';
            app.Iniciar_Medicion_Button.Text = "INICIAR MEDICIÓN EN EL TIEMPO";
        end

        % Value changed function: Factor_Suavizado_Brewster_Slider
        function Factor_Suavizado_Brewster_SliderValueChanged(app, event)
            % 1. Leer valor del Slider
            val = round(app.Factor_Suavizado_Brewster_Slider.Value);
            
            % 2. Escribirlo en el EditField
            app.Factor_Suavizado_Brewster_EditField.Value = val;
        end

        % Value changed function: Factor_Suavizado_Brewster_EditField
        function Factor_Suavizado_Brewster_EditFieldValueChanged(app, event)
            % 1. Leer el número que escribió el usuario
            val = app.Factor_Suavizado_Brewster_EditField.Value;
            
            % 2. Limitar al rango del Slider (1 a 50)
            if val < 1
                val = 1;
            elseif val > 50
                val = 50;
            end
            
            % 3. Actualizar ambos componentes (por si se corrigió el valor)
            app.Factor_Suavizado_Brewster_EditField.Value = val;
            app.Factor_Suavizado_Brewster_Slider.Value = val;
        end

        % Button pushed function: Simular_Ajuste_Curvas_Button
        function Simular_Ajuste_Curvas_ButtonPushed(app, event)
            %% 1. Leer Parámetros Generales (Usando los Spinners nuevos)
           try
               lambda = app.Longitud_Onda_Ajuste_Curvas_Spinner.Value;
               theta_min = app.Angulo_Inicial_Ajuste_Curvas_Spinner.Value;
               theta_max = app.Angulo_Final_Ajuste_Curvas_Spinner.Value;
               puntos = app.Numero_Puntos_Ajuste_Curvas_Spinner.Value;
               
               % Vector de ángulos para la simulación
               theta_vector = linspace(theta_min, theta_max, puntos);
           catch
               uialert(app.UIFigure, 'Error en los parámetros de simulación.', 'Error'); return;
           end
           
           %% 2. Ensamblar la Pila de Capas
           try
               % Vectores de n, k, d (Estos sí son EditFields numéricos en tu panel de capas)
               n_vals = [app.Sistemas_Multicapa_n1_EditField.Value; app.Sistemas_Multicapa_n2_EditField.Value; app.Sistemas_Multicapa_n3_EditField.Value; ...
                         app.Sistemas_Multicapa_n4_EditField.Value; app.Sistemas_Multicapa_n5_EditField.Value; app.Sistemas_Multicapa_n6_EditField.Value; ...
                         app.Sistemas_Multicapa_n7_EditField.Value; app.Sistemas_Multicapa_n8_EditField.Value; app.Sistemas_Multicapa_n9_EditField.Value];
               
               k_vals = [app.Sistemas_Multicapa_k1_EditField.Value; app.Sistemas_Multicapa_k2_EditField.Value; app.Sistemas_Multicapa_k3_EditField.Value; ...
                         app.Sistemas_Multicapa_k4_EditField.Value; app.Sistemas_Multicapa_k5_EditField.Value; app.Sistemas_Multicapa_k6_EditField.Value; ...
                         app.Sistemas_Multicapa_k7_EditField.Value; app.Sistemas_Multicapa_k8_EditField.Value; app.Sistemas_Multicapa_k9_EditField.Value];
               
               d_vals = [app.Sistemas_Multicapa_d1_EditField.Value; app.Sistemas_Multicapa_d2_EditField.Value; app.Sistemas_Multicapa_d3_EditField.Value; ...
                         app.Sistemas_Multicapa_d4_EditField.Value; app.Sistemas_Multicapa_d5_EditField.Value; app.Sistemas_Multicapa_d6_EditField.Value; ...
                         app.Sistemas_Multicapa_d7_EditField.Value; app.Sistemas_Multicapa_d8_EditField.Value; app.Sistemas_Multicapa_d9_EditField.Value];
               
               % Indice Complejo (N = n + ik)
               n_complex = n_vals + 1i * abs(k_vals);
               
               % Lógica inteligente de ensamblaje (Prisma -> Capas -> Salida)
               n_stack_final = [n_complex(1)]; % Siempre empezamos con la Capa 1 (Prisma)
               d_layers_final = [];
               
               for i = 2:9
                   if d_vals(i) > 0
                       % Si tiene grosor, es una capa interna
                       n_stack_final = [n_stack_final; n_complex(i)];
                       d_layers_final = [d_layers_final; d_vals(i)];
                   elseif d_vals(i) == 0
                       % Si el grosor es 0, asumimos que es el MEDIO FINAL (Aire/Sustrato) y paramos
                       n_stack_final = [n_stack_final; n_complex(i)];
                       break; 
                   end
               end
               
               if length(n_stack_final) < 2
                   uialert(app.UIFigure, 'Defina al menos Prisma y Medio Final (Capa 1 y 2).', 'Error Capas'); return;
               end
               
           catch
               uialert(app.UIFigure, 'Error leyendo la tabla de capas.', 'Error'); return;
           end
           
           %% 3. Calcular TMM
           try
               Rp = calcular_reflectancia_tmm(app, n_stack_final, d_layers_final, lambda, theta_vector);
           catch ME
               uialert(app.UIFigure, "Error matemático: " + ME.message, 'Error'); return;
           end
           
           %% 4. Graficar (MODO COMPARACIÓN)
           ax = app.Grafica_Ajuste_Curvas_Axes;
           hold(ax, 'on');
           
           % Buscar si ya existe una línea de simulación previa para actualizarla en vez de encimar
           simLine = findobj(ax, 'Tag', 'SimulacionLine');
           
           if isempty(simLine)
               % Si no existe, la creamos (Línea Roja Sólida)
               plot(ax, theta_vector, Rp, 'r-', 'LineWidth', 2, 'DisplayName', 'Modelo Teórico', 'Tag', 'SimulacionLine');
           else
               % Si existe, solo actualizamos los datos
               simLine.XData = theta_vector;
               simLine.YData = Rp;
           end
           
           title(ax, 'Comparación: Modelo Teórico vs Experimental');
           xlabel(ax, 'Ángulo de Incidencia (°)');
           ylabel(ax, 'Reflectancia Normalizada (R_p)');
           legend(ax, 'show', 'Location', 'best');
           grid(ax, 'on');
           
           % Ajustar límites
           xlim(ax, [theta_min, theta_max]);
           ylim(ax, [0 1.1]); % Reflectancia va de 0 a 1
        end

        % Button pushed function: Abrir_Archivo_Ajuste_Curvas_Button
        function Abrir_Archivo_Ajuste_Curvas_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.dat';'*.txt'}, 'Cargar Datos Experimentales');
            if isequal(file, 0), return; end

            app.Ubicacion_Archivo_Ajuste_Curvas_EditField.Value = fullfile(path, file);

            try
                % Leer archivo
                data = readmatrix(fullfile(path, file));
                angExp = data(:,1);
                intExp = data(:,2);

                % Normalización (0 a 1)
                maxVal = max(intExp);
                if maxVal > 0
                    intExpNorm = intExp / maxVal;
                else
                    intExpNorm = intExp;
                end

                % GUARDAR EN PROPIEDADES (SOLUCIÓN ROBUSTA)
                app.ExpData_Angulos = angExp;
                app.ExpData_Intensidad = intExpNorm;

                % Llamar a la función de actualización
                actualizarGraficaComparativa(app);

            catch ME
                uialert(app.UIFigure, "Error al leer archivo: " + ME.message, "Error");
            end
        end

        % Value changed function: Factor_Suavizado_Ajuste_Curvas_Slider
        function Factor_Suavizado_Ajuste_Curvas_SliderValueChanged(app, event)
            % Actualizar EditField
            app.Factor_Suavizado_Ajuste_Curvas_EditField.Value = round(app.Factor_Suavizado_Ajuste_Curvas_Slider.Value);
            % Refrescar gráfica
            actualizarGraficaComparativa(app);
        end

        % Value changed function: Offset_Angular_Ajuste_Curvas_Spinner
        function Offset_Angular_Ajuste_Curvas_SpinnerValueChanged(app, event)
            actualizarGraficaComparativa(app);
        end

        % Value changed function: Offset_Vertical_Ajuste_Curvas_Spinner
        function Offset_Vertical_Ajuste_Curvas_SpinnerValueChanged(app, event)
            actualizarGraficaComparativa(app);
        end

        % Value changed function: Factor_Escala_Ajuste_Curvas_Spinner
        function Factor_Escala_Ajuste_Curvas_SpinnerValueChanged(app, event)
            actualizarGraficaComparativa(app);
        end

        % Value changed function: Angulo_Final_Barrido_Spinner
        function Angulo_Final_Barrido_SpinnerValueChanged(app, event)
            value = app.Angulo_Final_Barrido_Spinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Aplicacion_GridLayout = uigridlayout(app.UIFigure);
            app.Funcion_TabGroup = uitabgroup(app.Aplicacion_GridLayout);
            app.Descripcion_Tab = uitab(app.Funcion_TabGroup);
            app.Descripcion_Tab.Title = 'Descripción';
            app.Descripcion_GridLayout = uigridlayout(app.Descripcion_Tab);
            app.Image = uiimage(app.Descripcion_GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'Captura de pantalla 2026-01-11 182818.png');
            app.SECIHTI_Imagen_2 = uiimage(app.Descripcion_GridLayout);
            app.SECIHTI_Imagen_2.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'Laboratorio de Superficies Ópticas sin Fondo.png');
            app.SECIHTI_Imagen = uiimage(app.Descripcion_GridLayout);
            app.SECIHTI_Imagen.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'logo_SECIHTI_vertical.png');
            app.ObjetivodelInstrumentoTextAreaLabel = uilabel(app.Descripcion_GridLayout);
            app.ObjetivodelInstrumentoTextAreaLabel.Text = 'Objetivo del Instrumento';
            app.ObjetivodelInstrumentoTextArea = uitextarea(app.Descripcion_GridLayout);
            app.ObjetivodelInstrumentoTextArea.Value = {'Este sistema mecatrónico automatizado está diseñado para la caracterización óptica de materiales mediante la medición precisa del Ángulo de Brewster y la aplicación de la técnica Abelês-Brewster. El dispositivo permite determinar el índice de refracción de muestras sólidas y películas delgadas mediante el análisis de la polarización de la luz reflejada.'; 'El sistema opera bajo una configuración goniométrica Theta-2-Theta, donde el motor de la muestra (theta) rota el material bajo prueba para variar el ángulo de incidencia. El motor del sensor (2-theta) rota un fotodetector al doble de velocidad angular para capturar el reflejo de la muestra.'};
            app.EspecificacionesTcnicasTextAreaLabel = uilabel(app.Descripcion_GridLayout);
            app.EspecificacionesTcnicasTextAreaLabel.Text = 'Especificaciones Técnicas';
            app.EspecificacionesTcnicasTextArea = uitextarea(app.Descripcion_GridLayout);
            app.EspecificacionesTcnicasTextArea.Value = {'Hardware de Control'; ''; '- Unidad de Procesamiento: ESP32-WROOM-32D (Dual Core, 240 MHz)'; '- Control de Movimiento: Motores a Pasos NEMA 23 con Driver Industrial TB6600.'; '- Transmisión: Tornillo sin fin con corona dentada que permite una resolución de 0.01° por paso.'; '- Sensores de Posición: Interruptor mecánico interno.'; '- Fuente de Luz: Diodo láser Newport de 639nm de longitud de onda controlado por PWM.'; '- Instrumentación Óptica: Divisor de haz plano 50/50'; '- Sensores Ópticos: Fotodiodos Hamamatsu de alta sensibilidad con adquisición ADC de 12-bits.'; ''; 'Software'; ''; '- Interfaz Gráfica desarrollada en App Designer de MATLAB.'; '- Comunicación Serial de alta velocidad (115200 baudios).'; '- Firmware en C++ con procesamiento multitareas.'};
            app.Barrido_Angular_Tab = uitab(app.Funcion_TabGroup);
            app.Barrido_Angular_Tab.Title = 'Barrido Angular';
            app.Barrido_Angular_GridLayout = uigridlayout(app.Barrido_Angular_Tab);
            app.Grafica_Barrido_Angular_Axes = uiaxes(app.Barrido_Angular_GridLayout);
            title(app.Grafica_Barrido_Angular_Axes, 'Gráfica de Barrido Ángular en Tiempo Real')
            xlabel(app.Grafica_Barrido_Angular_Axes, 'Ángulo (Grados)')
            ylabel(app.Grafica_Barrido_Angular_Axes, 'Intensidad (U.A.)')
            zlabel(app.Grafica_Barrido_Angular_Axes, 'Z')
            app.Grafica_Barrido_Angular_Axes.XLim = [10 90];
            app.Grafica_Barrido_Angular_Axes.YLim = [0 10];
            app.Grafica_Barrido_Angular_Axes.XTick = [15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Grafica_Barrido_Angular_Axes.XTickLabel = {'15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'};
            app.ParmetrosdeBarridongularPanel = uipanel(app.Barrido_Angular_GridLayout);
            app.ParmetrosdeBarridongularPanel.Title = 'Parámetros de Barrido Ángular';
            app.GridLayout4 = uigridlayout(app.ParmetrosdeBarridongularPanel);
            app.Iniciar_Barrido_Button = uibutton(app.GridLayout4, 'push');
            app.Iniciar_Barrido_Button.ButtonPushedFcn = createCallbackFcn(app, @Iniciar_Barrido_ButtonPushed, true);
            app.Iniciar_Barrido_Button.Text = 'INICIAR BARRIDO ANGULAR';
            app.AvanceLabel_2 = uilabel(app.GridLayout4);
            app.AvanceLabel_2.Text = 'Ángulo Final (°)';
            app.Angulo_Final_Barrido_Spinner = uispinner(app.GridLayout4);
            app.Angulo_Final_Barrido_Spinner.ValueChangedFcn = createCallbackFcn(app, @Angulo_Final_Barrido_SpinnerValueChanged, true);
            app.Angulo_Final_Barrido_Spinner.Value = 90;
            app.ResolucinLabel = uilabel(app.GridLayout4);
            app.ResolucinLabel.Text = 'Resolución';
            app.Resolucion_Barrido_DropDown = uidropdown(app.GridLayout4);
            app.Resolucion_Barrido_DropDown.Items = {'1°', '0.1°', '0.01°'};
            app.Resolucion_Barrido_DropDown.Value = '1°';
            app.LecturadeSensorLabel_2 = uilabel(app.Barrido_Angular_GridLayout);
            app.LecturadeSensorLabel_2.Text = {'Lectura de'; 'Sensor'};
            app.Lectura_Sensor_Duplicado_Slider = uislider(app.Barrido_Angular_GridLayout);
            app.Lectura_Sensor_Duplicado_Slider.Limits = [0 10];
            app.Lectura_Sensor_Duplicado_Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Lectura_Sensor_Duplicado_Slider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Lectura_Sensor_Duplicado_Slider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.Angulo_Fijo_Tab = uitab(app.Funcion_TabGroup);
            app.Angulo_Fijo_Tab.Title = 'Ángulo Fijo';
            app.Angulo_Fijo_GridLayout = uigridlayout(app.Angulo_Fijo_Tab);
            app.Grafica_Angulo_Fijo_Axes = uiaxes(app.Angulo_Fijo_GridLayout);
            title(app.Grafica_Angulo_Fijo_Axes, 'Gráfica de Ángulo Fijo en el Tiempo')
            xlabel(app.Grafica_Angulo_Fijo_Axes, 'Tiempo (Segundos)')
            ylabel(app.Grafica_Angulo_Fijo_Axes, 'Intensidad (U.A.)')
            zlabel(app.Grafica_Angulo_Fijo_Axes, 'Z')
            app.Grafica_Angulo_Fijo_Axes.XLim = [30 90];
            app.Grafica_Angulo_Fijo_Axes.YLim = [0 3.5];
            app.Grafica_Angulo_Fijo_Axes.XTick = [30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Grafica_Angulo_Fijo_Axes.XTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'};
            app.ParmetrosdenguloFijoPanel = uipanel(app.Angulo_Fijo_GridLayout);
            app.ParmetrosdenguloFijoPanel.Title = 'Parámetros de Ángulo Fijo';
            app.GridLayout7 = uigridlayout(app.ParmetrosdenguloFijoPanel);
            app.Iniciar_Medicion_Button = uibutton(app.GridLayout7, 'push');
            app.Iniciar_Medicion_Button.ButtonPushedFcn = createCallbackFcn(app, @Iniciar_Medicion_ButtonPushed, true);
            app.Iniciar_Medicion_Button.Text = 'INICIAR MEDICIÓN EN EL TIEMPO';
            app.AvanceLabel_3 = uilabel(app.GridLayout7);
            app.AvanceLabel_3.Text = 'Tiempo total (s)';
            app.Tiempo_Total_Spinner = uispinner(app.GridLayout7);
            app.Tiempo_Total_Spinner.Value = 300;
            app.ResolucinLabel_2 = uilabel(app.GridLayout7);
            app.ResolucinLabel_2.Text = 'T de Muestreo';
            app.Tiempo_Muestreo_DropDown = uidropdown(app.GridLayout7);
            app.Tiempo_Muestreo_DropDown.Items = {'10 ms', '100 ms', '1000 ms'};
            app.Tiempo_Muestreo_DropDown.Value = '100 ms';
            app.LecturadeSensorLabel_3 = uilabel(app.Angulo_Fijo_GridLayout);
            app.LecturadeSensorLabel_3.Text = {'Lectura de'; 'Sensor'};
            app.Lectura_Sensor_Triplicado_Slider = uislider(app.Angulo_Fijo_GridLayout);
            app.Lectura_Sensor_Triplicado_Slider.Limits = [0 3.5];
            app.Lectura_Sensor_Triplicado_Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5];
            app.Lectura_Sensor_Triplicado_Slider.MajorTickLabels = {'0', '0.5', '1.0', '1.5', '2.0', '2.5', '3.0', '3.5'};
            app.Lectura_Sensor_Triplicado_Slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2 2.05 2.1 2.15 2.2 2.25 2.3 2.35 2.4 2.45 2.5 2.55 2.6 2.65 2.7 2.75 2.8 2.85 2.9 2.95 3 3.05 3.1 3.15 3.2 3.25 3.3 3.35 3.4 3.45 3.5];
            app.Angulo_Brewster_Tab = uitab(app.Funcion_TabGroup);
            app.Angulo_Brewster_Tab.Title = 'Ángulo Brewster';
            app.GridLayout2 = uigridlayout(app.Angulo_Brewster_Tab);
            app.Grafica_Experimental_Brewster_Axes = uiaxes(app.GridLayout2);
            title(app.Grafica_Experimental_Brewster_Axes, 'Gráfica de Archivo Experimental')
            xlabel(app.Grafica_Experimental_Brewster_Axes, 'Tiempo (Segundos)')
            ylabel(app.Grafica_Experimental_Brewster_Axes, 'Intensidad (U.A.)')
            zlabel(app.Grafica_Experimental_Brewster_Axes, 'Z')
            app.Grafica_Experimental_Brewster_Axes.XLim = [30 90];
            app.Grafica_Experimental_Brewster_Axes.YLim = [0 3.5];
            app.Grafica_Experimental_Brewster_Axes.XTick = [30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Grafica_Experimental_Brewster_Axes.XTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'};
            app.Ubicacion_Parmetros_Archivo_Experimental_Brewster_Panel = uipanel(app.GridLayout2);
            app.Ubicacion_Parmetros_Archivo_Experimental_Brewster_Panel.Title = 'Ubicación y Parámetros de Archivo Experimental';
            app.GridLayout8 = uigridlayout(app.Ubicacion_Parmetros_Archivo_Experimental_Brewster_Panel);
            app.Analizar_Archivo_Brewster_Button = uibutton(app.GridLayout8, 'push');
            app.Analizar_Archivo_Brewster_Button.ButtonPushedFcn = createCallbackFcn(app, @Analizar_Archivo_Brewster_ButtonPushed, true);
            app.Analizar_Archivo_Brewster_Button.Text = 'ANALIZAR ARCHIVO';
            app.Abrir_Archivo_Brewster_Button = uibutton(app.GridLayout8, 'push');
            app.Abrir_Archivo_Brewster_Button.ButtonPushedFcn = createCallbackFcn(app, @Abrir_Archivo_Brewster_ButtonPushed, true);
            app.Abrir_Archivo_Brewster_Button.Text = 'ABRIR';
            app.Factor_Suavizado_Brewster_EditField = uieditfield(app.GridLayout8, 'numeric');
            app.Factor_Suavizado_Brewster_EditField.ValueChangedFcn = createCallbackFcn(app, @Factor_Suavizado_Brewster_EditFieldValueChanged, true);
            app.AvanceLabel_4 = uilabel(app.GridLayout8);
            app.AvanceLabel_4.Text = 'Ángulo Final';
            app.Angulo_Final_Brewster_Spinner = uispinner(app.GridLayout8);
            app.Angulo_Final_Brewster_Spinner.Value = 90;
            app.AvanceLabel_5 = uilabel(app.GridLayout8);
            app.AvanceLabel_5.Text = 'Ángulo Inicial';
            app.Angulo_Inicial_Brewster_Spinner = uispinner(app.GridLayout8);
            app.Angulo_Inicial_Brewster_Spinner.Value = 30;
            app.UbicacinEditFieldLabel = uilabel(app.GridLayout8);
            app.UbicacinEditFieldLabel.Text = 'Ubicación';
            app.Ubicacion_Archivo_Brewster_EditField = uieditfield(app.GridLayout8, 'text');
            app.FactordeSuavizadoSliderLabel = uilabel(app.GridLayout8);
            app.FactordeSuavizadoSliderLabel.Text = {'Factor de'; 'Suavizado'};
            app.Factor_Suavizado_Brewster_Slider = uislider(app.GridLayout8);
            app.Factor_Suavizado_Brewster_Slider.Limits = [1 50];
            app.Factor_Suavizado_Brewster_Slider.ValueChangedFcn = createCallbackFcn(app, @Factor_Suavizado_Brewster_SliderValueChanged, true);
            app.Factor_Suavizado_Brewster_Slider.Value = 10;
            app.Resultados_Brewster_Panel = uipanel(app.GridLayout2);
            app.Resultados_Brewster_Panel.Title = 'RESULTADOS';
            app.GridLayout5 = uigridlayout(app.Resultados_Brewster_Panel);
            app.Theta_RLabel = uilabel(app.GridLayout5);
            app.Theta_RLabel.Text = 'Theta_R';
            app.Theta_R_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_2 = uilabel(app.GridLayout5);
            app.Theta_RLabel_2.Text = 'Indice n';
            app.Indice_Refraccion_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_3 = uilabel(app.GridLayout5);
            app.Theta_RLabel_3.Text = 'Ángulo Medio';
            app.Angulo_Medio_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_4 = uilabel(app.GridLayout5);
            app.Theta_RLabel_4.Text = 'Ancho Curva';
            app.Ancho_Curva_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_5 = uilabel(app.GridLayout5);
            app.Theta_RLabel_5.Text = 'Max intensidad';
            app.Max_Intensidad_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_6 = uilabel(app.GridLayout5);
            app.Theta_RLabel_6.Text = 'Min intensidad';
            app.Min_Intensidad_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Theta_RLabel_7 = uilabel(app.GridLayout5);
            app.Theta_RLabel_7.Text = 'No. Muestras';
            app.Numero_Muestras_Brewster_EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Abeles_Brewster_Tab = uitab(app.Funcion_TabGroup);
            app.Abeles_Brewster_Tab.Title = 'Abelês-Brewster';
            app.GridLayout11 = uigridlayout(app.Abeles_Brewster_Tab);
            app.Grafica_Experimental_Abeles_Axes = uiaxes(app.GridLayout11);
            title(app.Grafica_Experimental_Abeles_Axes, 'Gráfica de Archivo Experimental')
            xlabel(app.Grafica_Experimental_Abeles_Axes, 'Tiempo (Segundos)')
            ylabel(app.Grafica_Experimental_Abeles_Axes, 'Intensidad (U.A.)')
            zlabel(app.Grafica_Experimental_Abeles_Axes, 'Z')
            app.Grafica_Experimental_Abeles_Axes.XLim = [30 90];
            app.Grafica_Experimental_Abeles_Axes.YLim = [0 3.5];
            app.Grafica_Experimental_Abeles_Axes.XTick = [30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Grafica_Experimental_Abeles_Axes.XTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'};
            app.Ubicacion_Parmetros_Archivos_Experimentales_Abeles_Panel = uipanel(app.GridLayout11);
            app.Ubicacion_Parmetros_Archivos_Experimentales_Abeles_Panel.Title = 'Ubicación y Parámetros de Archivos Experimentales';
            app.GridLayout10 = uigridlayout(app.Ubicacion_Parmetros_Archivos_Experimentales_Abeles_Panel);
            app.Abrir_Archivo_Sustrato_Abeles_Button = uibutton(app.GridLayout10, 'push');
            app.Abrir_Archivo_Sustrato_Abeles_Button.ButtonPushedFcn = createCallbackFcn(app, @Abrir_Archivo_Sustrato_Abeles_ButtonPushed, true);
            app.Abrir_Archivo_Sustrato_Abeles_Button.Text = 'ABRIR';
            app.Abrir_Archivo_Pelicula_Abeles_Button = uibutton(app.GridLayout10, 'push');
            app.Abrir_Archivo_Pelicula_Abeles_Button.ButtonPushedFcn = createCallbackFcn(app, @Abrir_Archivo_Pelicula_Abeles_ButtonPushed, true);
            app.Abrir_Archivo_Pelicula_Abeles_Button.Text = 'ABRIR';
            app.Factor_Suavizado_Abeles_EditField = uieditfield(app.GridLayout10, 'numeric');
            app.Calcular_Error_Abeles_Button = uibutton(app.GridLayout10, 'push');
            app.Calcular_Error_Abeles_Button.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Error_Abeles_ButtonPushed, true);
            app.Calcular_Error_Abeles_Button.Text = {'CALCULAR '; 'ERROR'};
            app.AvanceLabel_6 = uilabel(app.GridLayout10);
            app.AvanceLabel_6.Text = 'Ángulo Final';
            app.Angulo_Final_Abeles_Spinner = uispinner(app.GridLayout10);
            app.Angulo_Final_Abeles_Spinner.Value = 90;
            app.AvanceLabel_7 = uilabel(app.GridLayout10);
            app.AvanceLabel_7.Text = 'Ángulo Inicial';
            app.Angulo_Inicial_Abeles_Spinner = uispinner(app.GridLayout10);
            app.Angulo_Inicial_Abeles_Spinner.Value = 30;
            app.UbicacinSustratoLabel = uilabel(app.GridLayout10);
            app.UbicacinSustratoLabel.Text = 'Ubicación Sustrato';
            app.Ubicacion_Archivo_Sustrato_Abeles_EditField = uieditfield(app.GridLayout10, 'text');
            app.FactordeSuavizadoSliderLabel_2 = uilabel(app.GridLayout10);
            app.FactordeSuavizadoSliderLabel_2.Text = {'Factor de'; 'Suavizado'};
            app.Factor_Suavizado_Abeles_Slider = uislider(app.GridLayout10);
            app.Factor_Suavizado_Abeles_Slider.Limits = [1 50];
            app.Factor_Suavizado_Abeles_Slider.Value = 10;
            app.UbicacinSustratoLabel_2 = uilabel(app.GridLayout10);
            app.UbicacinSustratoLabel_2.Text = 'Ubicación Película';
            app.Ubicacion_Archivo_Pelicula_Abeles_EditField = uieditfield(app.GridLayout10, 'text');
            app.Resultados_Abeles_Panel = uipanel(app.GridLayout11);
            app.Resultados_Abeles_Panel.Title = 'RESULTADOS';
            app.GridLayout9 = uigridlayout(app.Resultados_Abeles_Panel);
            app.Theta_RLabel_8 = uilabel(app.GridLayout9);
            app.Theta_RLabel_8.Text = 'Ángulo de Cruce';
            app.Angulo_Cruce_Abeles_EditField = uieditfield(app.GridLayout9, 'numeric');
            app.Theta_RLabel_9 = uilabel(app.GridLayout9);
            app.Theta_RLabel_9.Text = 'n de Película';
            app.Indice_Refraccion_Pelicula_Abeles_EditField = uieditfield(app.GridLayout9, 'numeric');
            app.Theta_RLabel_10 = uilabel(app.GridLayout9);
            app.Theta_RLabel_10.Text = 'Error Experim';
            app.Error_Experimental_Abeles_EditField = uieditfield(app.GridLayout9, 'numeric');
            app.Theta_RLabel_11 = uilabel(app.GridLayout9);
            app.Theta_RLabel_11.Text = 'Angulo Min Peli';
            app.Angulo_Minimo_Pelicula_EditField = uieditfield(app.GridLayout9, 'numeric');
            app.Theta_RLabel_12 = uilabel(app.GridLayout9);
            app.Theta_RLabel_12.Text = 'Angulo Min Sust';
            app.Angulo_Minimo_Sustrato_EditField = uieditfield(app.GridLayout9, 'numeric');
            app.AjusteCurvasTab = uitab(app.Funcion_TabGroup);
            app.AjusteCurvasTab.Title = 'Ajuste Curvas';
            app.GridLayout13 = uigridlayout(app.AjusteCurvasTab);
            app.Grafica_Ajuste_Curvas_Axes = uiaxes(app.GridLayout13);
            title(app.Grafica_Ajuste_Curvas_Axes, 'Gráfica de Archivo Experimental')
            xlabel(app.Grafica_Ajuste_Curvas_Axes, 'Tiempo (Segundos)')
            ylabel(app.Grafica_Ajuste_Curvas_Axes, 'Intensidad (U.A.)')
            zlabel(app.Grafica_Ajuste_Curvas_Axes, 'Z')
            app.Grafica_Ajuste_Curvas_Axes.XLim = [30 90];
            app.Grafica_Ajuste_Curvas_Axes.YLim = [0 3.5];
            app.Grafica_Ajuste_Curvas_Axes.XTick = [30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.Grafica_Ajuste_Curvas_Axes.XTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'; '60'; '65'; '70'; '75'; '80'; '85'; '90'};
            app.Ubicacion_y_Parametros_Ajuste_Curvas_Panel = uipanel(app.GridLayout13);
            app.Ubicacion_y_Parametros_Ajuste_Curvas_Panel.Title = 'Ubicación y Parámetros de Archivos Experimentales';
            app.GridLayout12 = uigridlayout(app.Ubicacion_y_Parametros_Ajuste_Curvas_Panel);
            app.Abrir_Archivo_Ajuste_Curvas_Button = uibutton(app.GridLayout12, 'push');
            app.Abrir_Archivo_Ajuste_Curvas_Button.ButtonPushedFcn = createCallbackFcn(app, @Abrir_Archivo_Ajuste_Curvas_ButtonPushed, true);
            app.Abrir_Archivo_Ajuste_Curvas_Button.Text = 'ABRIR';
            app.Factor_Suavizado_Ajuste_Curvas_EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Simular_Ajuste_Curvas_Button = uibutton(app.GridLayout12, 'push');
            app.Simular_Ajuste_Curvas_Button.ButtonPushedFcn = createCallbackFcn(app, @Simular_Ajuste_Curvas_ButtonPushed, true);
            app.Simular_Ajuste_Curvas_Button.Text = 'SIMULAR';
            app.AvanceLabel_8 = uilabel(app.GridLayout12);
            app.AvanceLabel_8.Text = 'Ángulo Final';
            app.Angulo_Final_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Angulo_Final_Ajuste_Curvas_Spinner.Value = 90;
            app.AvanceLabel_9 = uilabel(app.GridLayout12);
            app.AvanceLabel_9.Text = 'Ángulo Inicial';
            app.Angulo_Inicial_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Angulo_Inicial_Ajuste_Curvas_Spinner.Value = 30;
            app.UbicacinSustratoLabel_3 = uilabel(app.GridLayout12);
            app.UbicacinSustratoLabel_3.Text = 'Ubicación de Archivo';
            app.Ubicacion_Archivo_Ajuste_Curvas_EditField = uieditfield(app.GridLayout12, 'text');
            app.FactordeSuavizadoLabel = uilabel(app.GridLayout12);
            app.FactordeSuavizadoLabel.Text = 'Factor de Suavizado';
            app.Factor_Suavizado_Ajuste_Curvas_Slider = uislider(app.GridLayout12);
            app.Factor_Suavizado_Ajuste_Curvas_Slider.Limits = [1 50];
            app.Factor_Suavizado_Ajuste_Curvas_Slider.ValueChangedFcn = createCallbackFcn(app, @Factor_Suavizado_Ajuste_Curvas_SliderValueChanged, true);
            app.Factor_Suavizado_Ajuste_Curvas_Slider.Value = 10;
            app.AvanceLabel_10 = uilabel(app.GridLayout12);
            app.AvanceLabel_10.Text = 'Longitud de onda';
            app.Longitud_Onda_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Longitud_Onda_Ajuste_Curvas_Spinner.Value = 639;
            app.AvanceLabel_11 = uilabel(app.GridLayout12);
            app.AvanceLabel_11.Text = 'No. de Puntos';
            app.Numero_Puntos_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Numero_Puntos_Ajuste_Curvas_Spinner.Value = 1000;
            app.OffsetLabel = uilabel(app.GridLayout12);
            app.OffsetLabel.Text = 'Offset Horizontal';
            app.Offset_Angular_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Offset_Angular_Ajuste_Curvas_Spinner.Step = 0.1;
            app.Offset_Angular_Ajuste_Curvas_Spinner.Limits = [-10 10];
            app.Offset_Angular_Ajuste_Curvas_Spinner.ValueChangedFcn = createCallbackFcn(app, @Offset_Angular_Ajuste_Curvas_SpinnerValueChanged, true);
            app.OffsetVerticalSpinnerLabel = uilabel(app.GridLayout12);
            app.OffsetVerticalSpinnerLabel.Text = 'Offset Vertical';
            app.Offset_Vertical_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Offset_Vertical_Ajuste_Curvas_Spinner.Step = 0.01;
            app.Offset_Vertical_Ajuste_Curvas_Spinner.Limits = [-1 1];
            app.Offset_Vertical_Ajuste_Curvas_Spinner.ValueChangedFcn = createCallbackFcn(app, @Offset_Vertical_Ajuste_Curvas_SpinnerValueChanged, true);
            app.FactordeEscalaLabel = uilabel(app.GridLayout12);
            app.FactordeEscalaLabel.Text = 'Factor de Escala';
            app.Factor_Escala_Ajuste_Curvas_Spinner = uispinner(app.GridLayout12);
            app.Factor_Escala_Ajuste_Curvas_Spinner.Step = 0.01;
            app.Factor_Escala_Ajuste_Curvas_Spinner.Limits = [0 5];
            app.Factor_Escala_Ajuste_Curvas_Spinner.ValueChangedFcn = createCallbackFcn(app, @Factor_Escala_Ajuste_Curvas_SpinnerValueChanged, true);
            app.Factor_Escala_Ajuste_Curvas_Spinner.Value = 1;
            app.Sistemas_Multicapa_Capas_Panel = uipanel(app.GridLayout13);
            app.Sistemas_Multicapa_Capas_Panel.Title = 'Capas';
            app.Sistemas_Multicapa_n_Label = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Sistemas_Multicapa_n_Label.Text = 'n';
            app.Sistemas_Multicapa_k_Label = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Sistemas_Multicapa_k_Label.Text = 'k';
            app.Sistemas_Multicapa_k1_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k2_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k2_EditField.Value = 3.5653;
            app.Sistemas_Multicapa_k3_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k3_EditField.Value = -3.2099;
            app.Sistemas_Multicapa_k4_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k5_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k6_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k7_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k8_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_k9_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_dnm_Label = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Sistemas_Multicapa_dnm_Label.Text = 'd(nm)';
            app.Sistemas_Multicapa_d1_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d2_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d2_EditField.Value = 3;
            app.Sistemas_Multicapa_d3_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d3_EditField.Value = 50;
            app.Sistemas_Multicapa_d4_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d5_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d6_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d7_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d8_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_d9_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Label = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label.Text = '1';
            app.Sistemas_Multicapa_n1_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_n1_EditField.Value = 1.515;
            app.Label_2 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_2.Text = '2';
            app.Sistemas_Multicapa_n2_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_n2_EditField.Value = 3.565;
            app.Label_3 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_3.Text = '3';
            app.Sistemas_Multicapa_n3_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_n3_EditField.Value = 0.1619;
            app.Label_4 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_4.Text = '4';
            app.Sistemas_Multicapa_n4_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Sistemas_Multicapa_n4_EditField.Value = 1;
            app.Label_5 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_5.Text = '5';
            app.Sistemas_Multicapa_n5_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Label_6 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_6.Text = '6';
            app.Sistemas_Multicapa_n6_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Label_7 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_7.Text = '7';
            app.Sistemas_Multicapa_n7_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Label_8 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_8.Text = '8';
            app.Sistemas_Multicapa_n8_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.Label_9 = uilabel(app.Sistemas_Multicapa_Capas_Panel);
            app.Label_9.Text = '9';
            app.Sistemas_Multicapa_n9_EditField = uieditfield(app.Sistemas_Multicapa_Capas_Panel, 'numeric');
            app.DocumentacinTab = uitab(app.Funcion_TabGroup);
            app.DocumentacinTab.Title = 'Documentación';
            app.HelpTextArea = uitextarea(app.DocumentacinTab);
            app.HelpTextArea.Value = {'A continuación se dará una dscripción de los principales controles e indicadores del sistema. La sección de la izquierda permanece fija mientras las pestañas del panel derecho permiten utilizar diferentes herramientas del sistema. '};
            app.Titulo_Label = uilabel(app.DocumentacinTab);
            app.Titulo_Label.Text = 'Manual de Operación - Sistema Theta-2Theta';
            app.HelpTextArea_2 = uitextarea(app.DocumentacinTab);
            app.HelpTextArea_2.Value = {'1. CONTROLES MANUALES Y AUTOMÁTICOS'; ' 1.1. MOVIMIENTO'; '  1.1.1. GIRO +/- (Muestra/Sensor): Permite mover en dirección positiva o negativa (sumando o '; '  restando pasos) las platinas rotatorias de la muestra y el sensor.'; '  1.1.2. Avance (°): Define la cantidad de grados que ejecutará el botón GIRO +/-.'; '  1.1.3. Ángulo Actual: Muestra la posición lógica actual de las platinas rotatorias.'; '  1.1.4. Detener motores: PARO DE EMERGENCIA de los motores independiente de su estado actual.'; ' 1.2. POSICIONAMIENTO Y CALIBRACIÓN'; '  1.2.1. IR A POSICIÓN 0°: Ejecuta una rutina de calibración de las platinas utilizando sensores '; '  mecánicos para establecer la posición 0° del sistema.'; '  1.2.2. GUARDAR INICIO: Memoriza la posición actual como un punto de referencia temporal.'; '  1.2.3. IR A INICIO: Regresa los motores al punto guardado anteriormente.'; '  1.2.4. 0°: Checkbox que indican cuando los motores están presionando el sensor mecánico de posición 0°.'; ' 1.3. PERIFERICOS'; '  1.3.1. Intensidad de Láser: Controla la potencia del diodo láser utilizando PWM.'; '  1.3.2. Leer Sensor: Realiza una lectura a los fotodetectores y actualiza su valor en el slider lectura de sensor.'};
            app.HelpTextArea_3 = uitextarea(app.DocumentacinTab);
            app.HelpTextArea_3.Value = {'2. HERRAMIENTAS DE ADQUISICIÓN DE DATOS EXPERIMENTALES'; ' 2.1. Barrido Angular'; '  2.1.1. Angulo Final (°): Define el ángulo final del barrido angular a partir de la posición actual.'; '  2.1.2. Resolución: Permite al usuario elegir entre tres resoluciones, siendo la de 0.01° su resolución máxima.'; '  2.1.3. INICIAR BARRIDO ANGULAR: Inicia el movimiento simultáneo de los motores y la adquisición de datos según '; '  la resolución seleccionada por el usuario. Se grafica en tiempo real y genera un documento .DAT con los valores.'; '  2.1.4. Lectura de Sensor: Muestra el valor en un tiempo determinado capturado por el sensor de luz fotodiodo.'; ' 2.2. Ángulo Fijo'; '  2.2.1. Tiempo total ( s): Define el tiempo total que el usuario desea capturar de datos.'; '  2.2.2. T de muestreo: Define cada cuánto tiempo desea el usuario tomar un dato del sensor.'; '  2.2.3. INICIAR MEDICIÓN EN EL TIEMPO: Comienza a tomar lecturas de los sensores en ángulo fijo a partir de los '; '  parámetros establecidos por el usuario Tiempo total y T de muestreo. Se grafica en tiempo real el resultado.'};
            app.HelpTextArea_4 = uitextarea(app.DocumentacinTab);
            app.HelpTextArea_4.Value = {'3. INFORMACIÓN DEL SISTEMA'; ' 3.1. CONEXIÓN DEL SISTEMA Y COMUNICACIÓN'; '  3.1.1. Puerto: Lista los puertos COM disponibles. Si no aparece ninguno, revisar la conexión USB y el cable USB.'; '  3.1.2. Conectar/Desconectar: Inicia o termina la comunicación 500,000 baudios. Espere el mensaje "ESP32 READY".'; '  3.1.3. SALIR: Cierra las comunicaciones y se sale de la aplicación de MATLAB de forma segura.'; '  3.1.4. Conexión establecida: Indica si hay enlace lógico con el microcontrolador.'; '  3.1.5. Sistema operando: Se enciende cuando los motores están en movimiento activo.'};
            app.HelpTextArea_5 = uitextarea(app.DocumentacinTab);
            app.HelpTextArea_5.Value = {'4. HERRAMIENTAS DE ANÁLISIS DE DATOS EXPERIMENTALES'; ' 4.1. Ángulo de Brewster. Determina el índice de refracción de materiales transparentes.'; ' 4.2. Método de Abelês-Brewster. Determina el índice de refracción de películas delgada dieléctricas.'; ' 4.3. Ajuste de Curvas. Permite cargar datos experimentales y compararlos con el modelo teórico de Fresnel.'};
            app.Controles_Manuales_Automticos_Panel = uipanel(app.Aplicacion_GridLayout);
            app.Controles_Manuales_Automticos_Panel.Title = 'Controles Manuales y Automáticos';
            app.GIRO_MENOS_muestra_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.GIRO_MENOS_muestra_Button.ButtonPushedFcn = createCallbackFcn(app, @GIRO_MENOS_muestra_ButtonPushed, true);
            app.GIRO_MENOS_muestra_Button.Text = 'GIRO -';
            app.GIRO_MAS_muestra_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.GIRO_MAS_muestra_Button.ButtonPushedFcn = createCallbackFcn(app, @GIRO_MAS_muestra_ButtonPushed, true);
            app.GIRO_MAS_muestra_Button.Text = 'GIRO +';
            app.MUESTRA_theta_Label = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.MUESTRA_theta_Label.Text = 'MUESTRA (theta)';
            app.nguloActualEditFieldLabel = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.nguloActualEditFieldLabel.Text = 'Ángulo Actual';
            app.Angulo_Actual_Mustra_EditField = uieditfield(app.Controles_Manuales_Automticos_Panel, 'numeric');
            app.AvanceLabel = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.AvanceLabel.Text = 'Avance (°)';
            app.Avance_Spinner = uispinner(app.Controles_Manuales_Automticos_Panel);
            app.Avance_Spinner.Value = 30;
            app.Detener_Motores_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.Detener_Motores_Button.ButtonPushedFcn = createCallbackFcn(app, @Detener_Motores_ButtonPushed, true);
            app.Detener_Motores_Button.Text = 'Detener motores';
            app.IntensidaddeLserSliderLabel = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.IntensidaddeLserSliderLabel.Text = {'Intensidad '; 'de Láser'};
            app.Intensidad_Laser_Slider = uislider(app.Controles_Manuales_Automticos_Panel);
            app.Intensidad_Laser_Slider.Limits = [0 5];
            app.Intensidad_Laser_Slider.ValueChangedFcn = createCallbackFcn(app, @Intensidad_Laser_SliderValueChanged, true);
            app.Leer_Sensor_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.Leer_Sensor_Button.ButtonPushedFcn = createCallbackFcn(app, @Leer_Sensor_ButtonPushed, true);
            app.Leer_Sensor_Button.Text = {'Leer'; 'Sensor'};
            app.Valor_Laser_EditField = uieditfield(app.Controles_Manuales_Automticos_Panel, 'numeric');
            app.Valor_Laser_EditField.ValueChangedFcn = createCallbackFcn(app, @Valor_Laser_EditFieldValueChanged, true);
            app.MUESTRA_0_grados_CheckBox = uicheckbox(app.Controles_Manuales_Automticos_Panel);
            app.MUESTRA_0_grados_CheckBox.Text = '0°';
            app.Ir_Home_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.Ir_Home_Button.ButtonPushedFcn = createCallbackFcn(app, @Ir_Home_ButtonPushed, true);
            app.Ir_Home_Button.Text = 'IR A POSICIÓN 0°';
            app.GIRO_MAS_sensor_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.GIRO_MAS_sensor_Button.ButtonPushedFcn = createCallbackFcn(app, @GIRO_MAS_sensor_ButtonPushed, true);
            app.GIRO_MAS_sensor_Button.Text = 'GIRO +';
            app.nguloActualEditFieldLabel_2 = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.nguloActualEditFieldLabel_2.Text = 'Ángulo Actual';
            app.Angulo_Actual_sensor_EditField = uieditfield(app.Controles_Manuales_Automticos_Panel, 'numeric');
            app.SENSOR_2theta_Label = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.SENSOR_2theta_Label.Text = 'SENSOR (2-theta)';
            app.GIRO_MENOS_sensor_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.GIRO_MENOS_sensor_Button.ButtonPushedFcn = createCallbackFcn(app, @GIRO_MENOS_sensor_ButtonPushed2, true);
            app.GIRO_MENOS_sensor_Button.Text = 'GIRO -';
            app.SENSOR_0_grados_CheckBox = uicheckbox(app.Controles_Manuales_Automticos_Panel);
            app.SENSOR_0_grados_CheckBox.Text = '0°';
            app.LecturadeSensorLabel = uilabel(app.Controles_Manuales_Automticos_Panel);
            app.LecturadeSensorLabel.Text = {'Lectura de'; 'Sensor'};
            app.Lectura_Sensor_Slider = uislider(app.Controles_Manuales_Automticos_Panel);
            app.Lectura_Sensor_Slider.Limits = [0 10];
            app.Lectura_Sensor_EditField = uieditfield(app.Controles_Manuales_Automticos_Panel, 'numeric');
            app.Guardar_Inicio_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.Guardar_Inicio_Button.ButtonPushedFcn = createCallbackFcn(app, @Guardar_Inicio_ButtonPushed, true);
            app.Guardar_Inicio_Button.Text = 'GUARDAR INICIO';
            app.Ir_Inicio_Button = uibutton(app.Controles_Manuales_Automticos_Panel, 'push');
            app.Ir_Inicio_Button.ButtonPushedFcn = createCallbackFcn(app, @Ir_Inicio_ButtonPushed, true);
            app.Ir_Inicio_Button.Text = 'IR A INICIO';
            app.Informacion_Sistema_Panel = uipanel(app.Aplicacion_GridLayout);
            app.Informacion_Sistema_Panel.Title = 'Información del Sistema';
            app.GridLayout = uigridlayout(app.Informacion_Sistema_Panel);
            app.Conectar_Button = uibutton(app.GridLayout, 'push');
            app.Conectar_Button.ButtonPushedFcn = createCallbackFcn(app, @Conectar_ButtonPushed, true);
            app.Conectar_Button.Text = 'Conectar';
            app.Desconectar_Button = uibutton(app.GridLayout, 'push');
            app.Desconectar_Button.ButtonPushedFcn = createCallbackFcn(app, @Desconectar_ButtonPushed, true);
            app.Desconectar_Button.Enable = 'off';
            app.Desconectar_Button.Text = 'Desconectar';
            app.SALIR_Button = uibutton(app.GridLayout, 'push');
            app.SALIR_Button.ButtonPushedFcn = createCallbackFcn(app, @SALIR_ButtonPushed, true);
            app.SALIR_Button.Text = 'SALIR';
            app.Conexion_Establecida_CheckBox = uicheckbox(app.GridLayout);
            app.Conexion_Establecida_CheckBox.Text = 'Conexión establecida';
            app.Sistema_Operando_CheckBox = uicheckbox(app.GridLayout);
            app.Sistema_Operando_CheckBox.Text = 'Sistema operando';
            app.PuertoDropDownLabel = uilabel(app.GridLayout);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto_DropDown = uidropdown(app.GridLayout);
            app.MonitorSerialLabel = uilabel(app.GridLayout);
            app.MonitorSerialLabel.Text = 'Monitor Serial';
            app.Monitor_Serial_TextArea = uitextarea(app.GridLayout);
            app.Presentacion_Panel = uipanel(app.Aplicacion_GridLayout);
            app.Titulo_Proyecto_TextArea = uitextarea(app.Presentacion_Panel);
            app.Titulo_Proyecto_TextArea.Editable = 'off';
            app.Titulo_Proyecto_TextArea.Value = {'Programa de Control de Motores a Pasos para Medición de Ángulo de Brewster y Técnica de Abelês-Brewster'};
            app.Desarrolladores_TextArea = uitextarea(app.Presentacion_Panel);
            app.Desarrolladores_TextArea.Editable = 'off';
            app.Desarrolladores_TextArea.Value = {'Desarrollado por: MC. Oscar Silva, Dr. Donato Luna & Dr. Osvaldo Rodriguez'};
            app.CIO_Imagen = uiimage(app.Presentacion_Panel);
            app.CIO_Imagen.ImageSource = fullfile(pathToMLAPP, 'Imagenes', 'cio_negro.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlSistemaPortatil5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end